classdef Diagnostico_energetico < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel_2                        matlab.ui.container.Panel
        SistemafotovoltaicoButton      matlab.ui.control.Button
        SalirButton                    matlab.ui.control.Button
        SistemadeTierrasButton         matlab.ui.control.Button
        AnalizadorButton               matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        CompensadaLabel                matlab.ui.control.Label
        TotalLabel                     matlab.ui.control.Label
        T1                             matlab.ui.control.EditField
        T2                             matlab.ui.control.EditField
        Q_R                            matlab.ui.control.EditField
        Q_R_2                          matlab.ui.control.EditField
        P_A                            matlab.ui.control.EditField
        Image2                         matlab.ui.control.Image
        ClculoparaseleccionarelbancodecapacitoresPanel  matlab.ui.container.Panel
        NuevodeBonificacinEditField    matlab.ui.control.EditField
        NuevodeBonificacinEditFieldLabel  matlab.ui.control.Label
        ResultadosLabel                matlab.ui.control.Label
        PotenciaDemandadaWLabel        matlab.ui.control.Label
        TiempoDropDown                 matlab.ui.control.DropDown
        PeriodoaConsiderarLabel        matlab.ui.control.Label
        CoB                            matlab.ui.control.TextArea
        CobrooDescuentoTextAreaLabel   matlab.ui.control.Label
        PoB                            matlab.ui.control.TextArea
        PenalizacinoBonificacinLabel   matlab.ui.control.Label
        CU                             matlab.ui.control.NumericEditField
        CargototalantesdelfactordePotenciaEditFieldLabel  matlab.ui.control.Label
        FP3                            matlab.ui.control.NumericEditField
        FactordePotenciaDeseadoLabel   matlab.ui.control.Label
        FP2                            matlab.ui.control.NumericEditField
        ltimoFactordePotenciaLabel     matlab.ui.control.Label
        FP1                            matlab.ui.control.NumericEditField
        FactordePotencia1aoLabel       matlab.ui.control.Label
        CC                             matlab.ui.control.TextArea
        CapacidaddelBandodeCapacitoresLabel  matlab.ui.control.Label
        PBC                            matlab.ui.control.TextArea
        PotenciadelbancodeCapacitoresLabel  matlab.ui.control.Label
        VoltajeInstaladoDropDown       matlab.ui.control.DropDown
        VoltajeInstaladoDropDownLabel  matlab.ui.control.Label
        P                              matlab.ui.control.NumericEditField
        AyudaPanel                     matlab.ui.container.Panel
        HELPButton                     matlab.ui.control.Button
        R_I                            matlab.ui.container.Panel
        CalcularRetornodeInversionButton  matlab.ui.control.Button
        T_I                            matlab.ui.control.EditField
        TiempodeRetornoInversinEditFieldLabel  matlab.ui.control.Label
        C_B_C                          matlab.ui.control.EditField
        IngreseCostodelBancodeCondensadoresLabel  matlab.ui.control.Label
        BuscarButton                   matlab.ui.control.StateButton
        BorrarButton                   matlab.ui.control.StateButton
        CalcularButton                 matlab.ui.control.StateButton
    end


    properties (Access = public)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            %%%%%%%%%%%%%%%%%%%%%Codigo para popup informativo%%%%%%%%%%%%%%%%%
            app.UIFigure.Visible = 'off'; % Hide the main app's figure
            f = msgbox("La siguiente interfaz se diseñó con el propósito de calcular el factor de potencia y los datos para corregir lo en caso de que tenga un deficit de esto.","Bienvenido","help");
            uiwait(f); % Wait for the message box to be closed
            app.UIFigure.Visible = 'on'; % Show the main app's figure


            %%%%%%%%%%%%Codigo para centrar la interfaz principal%%%%%%%%%%%%%%%%%

            % Get the screen size
            screenSize = get(groot,'ScreenSize');

            % Get the main app's size
            appSize = app.UIFigure.Position(3:4);

            % Calculate the position of the main app to center it on the screen
            xPos = (screenSize(3) - appSize(1)) / 2;
            yPos = (screenSize(4) - appSize(2)) / 2;

            % Set the position of the main app
            app.UIFigure.Position(1) = xPos;
            app.UIFigure.Position(2) = yPos;

            % Show the main app's figure
            app.UIFigure.Visible = 'on';


            %%%%%%%%%%%%%%%%%%%%%%%Codigo para los campos editables/dropdown%%%%%%

            app.VoltajeInstaladoDropDown.Items= {'Seleccione', '240V', '480V'};
            app.TiempoDropDown.Items= {'Seleccione', '1 mes', '1 año'};
            app.P.Enable="off";
            app.FP2.Enable="off";
            app.FP3.Enable="off";
            app.CU.Enable="off";
            app.FP1.Enable = "off";
            app.TiempoDropDown.Enable="off";


        end

        % Value changed function: CalcularButton
        function CalcularButtonValueChanged(app, event)
            value = app.CalcularButton.Value;

            %%%%%%%%%%%%%%%%Seleccion del voltaje instalado%%%%%%%%%%%%%%%

            % %%%%%%%%%% En caso de seleccionar 240 volts%%%%%%%%%%%%%%
            if app.VoltajeInstaladoDropDown.Value == '240V'
                V=240

                if app.TiempoDropDown.Value == '2' %Seleccion del tiempo a evaluar 1 mes
                    app.NuevodeBonificacinEditField.Enable='on';
                    T=1; %Tiempo equivalente a 1 mes
                    P=app.P.Value;%Potencia demandada
                    FP1=app.FP1.Value; %Factor de potencia promedio
                    FP2=app.FP2.Value; %Factor de potencia ultimo
                    FP3=app.FP3.Value; %Factor de potencia deseado
                    CT=app.CU.Value; %Cargo antes del factor de potencia
                    TH1=acosd(FP2/100);%Angulo de theta1
                    TH2=acosd(FP3/100);%Angulo de theta2


                    Bonx=((1/4)*(1-(90/FP3)))*100;
                    Bonx_rounded=round(Bonx,1);

                    app.NuevodeBonificacinEditField.Value=(num2str(Bonx_rounded)+"%");


                    if FP2<90 &&FP2>0 %Comparando si el ultimo FP es menor a 90% para calcular el banco de capacitores para evitar penalización segun CFE
                        Pen=(3/5*((90/FP2)-1)*100); %calculando penalizacion con el ultimo factor de potencia

                        %Estableciendo los limites de la penalizacion mayor a
                        %2.5%
                        %y menor o igual a 120%
                        if Pen > 2.5 && Pen <= 120
                            % La penalización está dentro del rango deseado
                        else
                            % La penalización está fuera del rango deseado, por lo tanto se establece en el límite superior de 120
                            Pen = min(Pen, 120); % Establecer límite superior en 120%
                        end
                        Pen_rounded = round(Pen,0);
                        app.PoB.Value=("Penalizacion de: "+ num2str(Pen_rounded)+ "%");
                        CoD=num2str((Pen_rounded/100)*CT);
                        app.CoB.Value="Cobro de: "+ (CoD);
                        Q=(tand(TH1))*(P);
                        Qd=(tand(TH2))*(P);
                        Qc=Q-Qd;

                        %                         app.Q_R_2.Value=(num2str(Q)+" kVAR");
                        %                         app.Q_R.Value=(num2str(Qd)+ " kVAR");
                        app.T1.Value=num2str(TH1+"°");
                        app.T2.Value=num2str(TH2+"°");
                        if app.P.Value>=1 && app.P.Value<1000
                            app.P_A.Value=(num2str(app.P.Value)+ " KW");
                        elseif P>=1000 && P<1000000
                            app.P_A.Value=(num2str(app.P.Value/1000)+ " KW");
                        elseif P>=1000000
                            app.P_A.Value=(num2str(app.P.Value/1000000)+ " MW");
                        end

                        %Comparador cuando Q equivale a VAR o KVAR
                        if Qc>=1 && Qc<1000
                            app.PBC.Value=(num2str(Qc)+" VAR");
                        elseif Qc>=1000 && Qc<1000000
                            app.PBC.Value=(num2str(Qc/1000)+" KVAR");
                        elseif Qc>=1000000
                            app.PBC.Value=(num2str(Qc/1000000)+ "MVAR");

                        end
                        if Q>=1 && Q<1000
                            app.Q_R_2.Value=(num2str(Q)+" VAR");
                        elseif Q>=1000 && Q<1000000
                            app.Q_R_2.Value=(num2str(Q/1000)+" kVAR");
                        elseif Q>1000000
                            app.Q_R_2.Value=(num2str(Q/1000000)+" MVAR");
                        end
                        if Qd>=1 && Qd<1000
                            app.Q_R.Value=(num2str(Qd)+ " VAR");
                        elseif Qd>=1000 && Qd<1000000
                            app.Q_R.Value=(num2str(Qd/1000)+ " kVAR");
                        elseif Qd>=1000000
                            app.Q_R.Value=(num2str(Qd/1000000)+ " VAR");
                        end
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        F=60; %Frecuencia a 60Hz
                        C=(Qc)/(2*pi*F*(V)^2);
                        %Comaparador para saber su notacion cientifica con
                        %relacion a su unidad
                        if C >= 1
                            app.CC.Value=(num2str(C)+"F");
                        elseif C >= 0.001
                            app.CC.Value=(num2str(C*1000)+"mF");
                        elseif C >= 0.000001
                            app.CC.Value=(num2str(C*1000000)+"µF");
                        else
                            app.CC.Value=(num2str(C*1000000000)+"nF");
                        end

                    elseif FP2>=90 && FP2<=100
                        Bon= (1/4*(1-(90/FP2))*100);
                        Bon = max(Bon, 0); % Establecer límite inferior en 0%
                        Bon = min(Bon, 2.5); % Establecer límite superior en 2.5%
                        Bon_rounded=round(Bon,1);

                        app.PoB.Value=("Bonificacion de: "+ num2str((Bon_rounded))+ "%");
                        CoD=num2str(Bon_rounded*CT/100);

                        app.CoB.Value=("Descuento de: "+(CoD)+"-")
                        Q=(tand(TH1))*(P);
                        Qd=(tand(TH2))*(P);
                        Qc=Q-Qd;
                        %
                        %                            app.Q_R_2.Value=(num2str(Q)+" kVAR");
                        %                         app.Q_R.Value=(num2str(Qd)+ " kVAR");
                        app.T1.Value=num2str(TH1+"°");
                        app.T2.Value=num2str(TH2+"°");
                        if app.P.Value>=1 && app.P.Value<1000
                            app.P_A.Value=(num2str(app.P.Value)+ " KW");
                        elseif P>=1000 && P<1000000
                            app.P_A.Value=(num2str(app.P.Value/1000)+ " KW");
                        elseif P>=1000000
                            app.P_A.Value=(num2str(app.P.Value/1000000)+ " MW");
                        end

                        %Comparador cuando Q equivale a VAR o KVAR
                        if Qc>=1 && Qc<1000
                            app.PBC.Value=(num2str(Qc)+" VAR");
                        elseif Qc>=1000 && Qc<1000000
                            app.PBC.Value=(num2str(Qc/1000)+" KVAR");
                        elseif Qc>=1000000
                            app.PBC.Value=(num2str(Qc/1000000)+ "MVAR");

                        end
                        if Q>=1 && Q<1000
                            app.Q_R_2.Value=(num2str(Q)+" VAR");
                        elseif Q>=1000 && Q<1000000
                            app.Q_R_2.Value=(num2str(Q/1000)+" kVAR");
                        elseif Q>1000000
                            app.Q_R_2.Value=(num2str(Q/1000000)+" MVAR");
                        end
                        if Qd>=1 && Qd<1000
                            app.Q_R.Value=(num2str(Qd)+ " VAR");
                        elseif Qd>=1000 && Qd<1000000
                            app.Q_R.Value=(num2str(Qd/1000)+ " kVAR");
                        elseif Qd>=1000000
                            app.Q_R.Value=(num2str(Qd/1000000)+ " VAR");
                        end
                        F=60;%Frecuencia a 60Hz
                        C=(Qc)/(2*pi*F*(V)^2); %Calculo para la capacidad del condensador en faradios
                        %Comaparador para saber su notacion cientifica con
                        %relacion a su unidad
                        if C >= 1
                            app.CC.Value=(num2str(C)+"F");
                        elseif C >= 0.001
                            app.CC.Value=(num2str(C*1000)+"mF");
                        elseif C >= 0.000001
                            app.CC.Value=(num2str(C*1000000)+"µF");
                        else
                            app.CC.Value=(num2str(C*1000000000)+"nF");
                        end

                    end


                elseif app.TiempoDropDown.Value == '3'%Seleccion del tiempo a evaluar 1 año
                    app.NuevodeBonificacinEditField.Enable='on';

                    T=12; %Tiempo equivalente a 12 meses
                    P=app.P.Value;%Potencia demandada
                    FP1=app.FP1.Value; %Factor de potencia promedio
                    FP2=app.FP2.Value; %Factor de potencia ultimo
                    FP3=app.FP3.Value; %Factor de potencia deseado
                    CT=app.CU.Value; %Cargo antes del factor de potencia
                    TH1=acosd(FP1/100);%Angulo de theta1
                    TH2=acosd(FP3/100);%Angulo de theta2


                    Bonx=((1/4)*(1-(90/FP3)))*100;
                    Bonx_rounded=round(Bonx,1);
                    app.NuevodeBonificacinEditField.Value=(num2str(Bonx_rounded)+"%");


                    if FP2<90 &&FP2>0 %Comparando si el ultimo FP es menor a 90% para calcular el banco de capacitores para evitar penalización segun CFE
                        Pen=(3/5*((90/FP2)-1)*100); %calculando penalizacion con el ultimo factor de potencia
                        %Estableciendo los limites de la penalizacion mayor a
                        %2.5%
                        %y menor o igual a 120%
                        if Pen > 2.5 && Pen <= 120
                            % La penalización está dentro del rango deseado
                        else
                            % La penalización está fuera del rango deseado, por lo tanto se establece en el límite superior de 120
                            Pen = min(Pen, 120); % Establecer límite superior en 120%
                        end
                        Pen_rounded = round(Pen,1);
                        app.PoB.Value=("Penalizacion de: "+ num2str(Pen_rounded)+ "%");
                        CoD=num2str((Pen_rounded/100)*CT);
                        app.CoB.Value="Cobro de: "+ (CoD);
                        Q=(tand(TH1))*(P);
                        Qd=(tand(TH2))*(P);
                        Qc=Q-Qd;

                        %                         app.Q_R_2.Value=(num2str(Q)+" kVAR");
                        %                         app.Q_R.Value=(num2str(Qd)+ " kVAR");
                        app.T1.Value=num2str(TH1+"°");
                        app.T2.Value=num2str(TH2+"°");
                        if app.P.Value>=1 && app.P.Value<1000
                            app.P_A.Value=(num2str(app.P.Value)+ " KW");
                        elseif P>=1000 && P<1000000
                            app.P_A.Value=(num2str(app.P.Value/1000)+ " KW");
                        elseif P>=1000000
                            app.P_A.Value=(num2str(app.P.Value/1000000)+ " MW");
                        end


                        %Comparador cuando Q equivale a VAR o KVAR
                        if Qc>=1 && Qc<1000
                            app.PBC.Value=(num2str(Qc)+" VAR");
                        elseif Qc>=1000 && Qc<1000000
                            app.PBC.Value=(num2str(Qc/1000)+" KVAR");
                        elseif Qc>=1000000
                            app.PBC.Value=(num2str(Qc/1000000)+ "MVAR");

                        end
                        if Q>=1 && Q<1000
                            app.Q_R_2.Value=(num2str(Q)+" VAR");
                        elseif Q>=1000 && Q<1000000
                            app.Q_R_2.Value=(num2str(Q/1000)+" kVAR");
                        elseif Q>1000000
                            app.Q_R_2.Value=(num2str(Q/1000000)+" MVAR");
                        end
                        if Qd>=1 && Qd<1000
                            app.Q_R.Value=(num2str(Qd)+ " VAR");
                        elseif Qd>=1000 && Qd<1000000
                            app.Q_R.Value=(num2str(Qd/1000)+ " kVAR");
                        elseif Qd>=1000000
                            app.Q_R.Value=(num2str(Qd/1000000)+ " VAR");
                        end

                        F=60; %Frecuencia a 60Hz
                        C=(Qc)/(2*pi*F*(V)^2);
                        %Comaparador para saber su notacion cientifica con
                        %relacion a su unidad
                        if C >= 1
                            app.CC.Value=(num2str(C)+"F");
                        elseif C >= 0.001
                            app.CC.Value=(num2str(C*1000)+"mF");
                        elseif C >= 0.000001
                            app.CC.Value=(num2str(C*1000000)+"µF");
                        else
                            app.CC.Value=(num2str(C*1000000000)+"nF");
                        end

                    elseif FP2>=90 && FP2<=100
                        Bon= (1/4*(1-(90/FP2))*100);
                        Bon = max(Bon, 0); % Establecer límite inferior en 0%
                        Bon = min(Bon, 2.5); % Establecer límite superior en 2.5%
                        Bon_rounded=round(Bon,1);

                        app.PoB.Value=("Bonificacion de: "+ num2str((Bon_rounded))+ "%");
                        CoD=num2str(Bon_rounded*CT/100);

                        app.CoB.Value=("Descuento de: "+(CoD)+"-")
                        Q=(tand(TH1))*(P/12);
                        Qd=(tand(TH2))*(P/12);
                        Qc=Q-Qd;

                        %                            app.Q_R_2.Value=(num2str(Q)+" kVAR");
                        %                         app.Q_R.Value=(num2str(Qd)+ " kVAR");
                        app.T1.Value=num2str(TH1+"°");
                        app.T2.Value=num2str(TH2+"°");
                        app.P_A.Value=(num2str(app.P.Value)+ " KW");
                        if app.P.Value>=1 && app.P.Value<1000
                            app.P_A.Value=(num2str(app.P.Value)+ " KW");
                        elseif P>=1000 && P<1000000
                            app.P_A.Value=(num2str(app.P.Value/1000)+ " KW");
                        elseif P>=1000000
                            app.P_A.Value=(num2str(app.P.Value/1000000)+ " MW");
                        end


                        %Comparador cuando Q equivale a VAR o KVAR
                        if Qc>=1 && Qc<1000
                            app.PBC.Value=(num2str(Qc)+" VAR");
                        elseif Qc>=1000 && Qc<1000000
                            app.PBC.Value=(num2str(Qc/1000)+" KVAR");
                        elseif Qc>=1000000
                            app.PBC.Value=(num2str(Qc/1000000)+ "MVAR");

                        end
                        if Q>=1 && Q<1000
                            app.Q_R_2.Value=(num2str(Q)+" VAR");
                        elseif Q>=1000 && Q<1000000
                            app.Q_R_2.Value=(num2str(Q/1000)+" kVAR");
                        elseif Q>1000000
                            app.Q_R_2.Value=(num2str(Q/1000000)+" MVAR");
                        end
                        if Qd>=1 && Qd<1000
                            app.Q_R.Value=(num2str(Qd)+ " VAR");
                        elseif Qd>=1000 && Qd<1000000
                            app.Q_R.Value=(num2str(Qd/1000)+ " kVAR");
                        elseif Qd>=1000000
                            app.Q_R.Value=(num2str(Qd/1000000)+ " VAR");
                        end
                        F=60;%Frecuencia a 60Hz
                        C=(Qc)/(2*pi*F*(V)^2); %Calculo para la capacidad del condensador en faradios
                        %Comaparador para saber su notacion cientifica con
                        %relacion a su unidad
                        if C >= 1
                            app.CC.Value=(num2str(C)+"F");
                        elseif C >= 0.001
                            app.CC.Value=(num2str(C*1000)+"mF");
                        elseif C >= 0.000001
                            app.CC.Value=(num2str(C*1000000)+"µF");
                        else
                            app.CC.Value=(num2str(C*1000000000)+"nF");
                        end

                    end


                end


                %%%%%%%%%%%%%%%%%%%%%%%%% En cado de seleccionar 480 volts%%%%%%%%%%%%%%
            elseif app.VoltajeInstaladoDropDown.Value == '480V' %Seleccion del tiempo a evaluar
                V=480
                if app.TiempoDropDown.Value == '2'%Seleccion del tiempo a evaluar
                    app.NuevodeBonificacinEditField.Enable='on';

                    T=1; %Tiempo equivalente a 1 mes
                    P=app.P.Value;%Potencia demandada
                    FP1=app.FP1.Value; %Factor de potencia promedio
                    FP2=app.FP2.Value; %Factor de potencia ultimo
                    FP3=app.FP3.Value; %Factor de potencia deseado
                    CT=app.CU.Value; %Cargo antes del factor de potencia
                    TH1=acosd(FP2/100);%Angulo de theta1
                    TH2=acosd(FP3/100);%Angulo de theta2


                    Bonx=((1/4)*(1-(90/FP3)))*100;
                    Bonx_rounded=round(Bonx,1);
                    app.NuevodeBonificacinEditField.Value=(num2str(Bonx_rounded)+"%");


                    if FP2<90 &&FP2>0 %Comparando si el ultimo FP es menor a 90% para calcular el banco de capacitores para evitar penalización segun CFE
                        Pen=(3/5*((90/FP2)-1)*100); %calculando penalizacion con el ultimo factor de potencia
                        %Estableciendo los limites de la penalizacion mayor a
                        %2.5%
                        %y menor o igual a 120%
                        if Pen > 2.5 && Pen <= 120
                            % La penalización está dentro del rango deseado
                        else
                            % La penalización está fuera del rango deseado, por lo tanto se establece en el límite superior de 120
                            Pen = min(Pen, 120); % Establecer límite superior en 120%
                        end
                        Pen_rounded = round(Pen,0);
                        app.PoB.Value=("Penalizacion de: "+ num2str(Pen_rounded)+ "%");
                        CoD=num2str((Pen_rounded/100)*CT);
                        app.CoB.Value="Cobro de: "+ (CoD);
                        Q=(tand(TH1))*(P);
                        Qd=(tand(TH2))*(P);
                        Qc=Q-Qd;


                        %                         app.Q_R_2.Value=(num2str(Q)+" kVAR");
                        %                         app.Q_R.Value=(num2str(Qd)+ " kVAR");
                        app.T1.Value=num2str(TH1+"°");
                        app.T2.Value=num2str(TH2+"°");
                        if app.P.Value>=1 && app.P.Value<1000
                            app.P_A.Value=(num2str(app.P.Value)+ " W");
                        elseif P>=1000
                            app.P_A.Value=(num2str(app.P.Value/1000)+ " KW");
                        end

                        %Comparador cuando Q equivale a VAR o KVAR
                        if Qc>=1 && Qc<1000
                            app.PBC.Value=(num2str(Qc)+" VAR");
                        elseif Qc>=1000 && Qc<1000000
                            app.PBC.Value=(num2str(Qc/1000)+" KVAR");
                        elseif Qc>=1000000
                            app.PBC.Value=(num2str(Qc/1000000)+ "MVAR");

                        end
                        if Q>=1 && Q<1000
                            app.Q_R_2.Value=(num2str(Q)+" VAR");
                        elseif Q>=1000 && Q<1000000
                            app.Q_R_2.Value=(num2str(Q/1000)+" kVAR");
                        elseif Q>1000000
                            app.Q_R_2.Value=(num2str(Q/1000000)+" MVAR");
                        end
                        if Qd>=1 && Qd<1000
                            app.Q_R.Value=(num2str(Qd)+ " VAR");
                        elseif Qd>=1000 && Qd<1000000
                            app.Q_R.Value=(num2str(Qd/1000)+ " kVAR");
                        elseif Qd>=1000000
                            app.Q_R.Value=(num2str(Qd/1000000)+ " VAR");
                        end
                        F=60; %Frecuencia a 60Hz
                        C=(Qc)/(2*pi*F*(V)^2);
                        %Comaparador para saber su notacion cientifica con
                        %relacion a su unidad
                        if C >= 1
                            app.CC.Value=(num2str(C)+"F");
                        elseif C >= 0.001
                            app.CC.Value=(num2str(C*1000)+"mF");
                        elseif C >= 0.000001
                            app.CC.Value=(num2str(C*1000000)+"µF");
                        else
                            app.CC.Value=(num2str(C*1000000000)+"nF");
                        end

                    elseif FP2>=90 && FP2<=100
                        Bon= (1/4*(1-(90/FP2))*100);
                        Bon = max(Bon, 0); % Establecer límite inferior en 0%
                        Bon = min(Bon, 2.5); % Establecer límite superior en 2.5%
                        Bon_rounded=round(Bon,1);

                        app.PoB.Value=("Bonificacion de: "+ num2str((Bon_rounded))+ "%");
                        CoD=num2str(Bon_rounded*CT/100);

                        app.CoB.Value=("Descuento de: "+(CoD)+"-")
                        Q=(tand(TH1))*(P);
                        Qd=(tand(TH2))*(P);
                        Qc=Q-Qd;

                        %                            app.Q_R_2.Value=(num2str(Q)+" kVAR");
                        %                         app.Q_R.Value=(num2str(Qd)+ " kVAR");
                        app.T1.Value=num2str(TH1+"°");
                        app.T2.Value=num2str(TH2+"°");
                        if app.P.Value>=1 && app.P.Value<1000
                            app.P_A.Value=(num2str(app.P.Value)+ " W");
                        elseif P>=1000
                            app.P_A.Value=(num2str(app.P.Value/1000)+ " KW");
                        end


                        %Comparador cuando Q equivale a VAR o KVAR
                        if Qc>=1 && Qc<1000
                            app.PBC.Value=(num2str(Qc)+" VAR");
                        elseif Qc>=1000 && Qc<1000000
                            app.PBC.Value=(num2str(Qc/1000)+" KVAR");
                        elseif Qc>=1000000
                            app.PBC.Value=(num2str(Qc/1000000)+ "MVAR");

                        end
                        if Q>=1 && Q<1000
                            app.Q_R_2.Value=(num2str(Q)+" VAR");
                        elseif Q>=1000 && Q<1000000
                            app.Q_R_2.Value=(num2str(Q/1000)+" kVAR");
                        elseif Q>1000000
                            app.Q_R_2.Value=(num2str(Q/1000000)+" MVAR");
                        end
                        if Qd>=1 && Qd<1000
                            app.Q_R.Value=(num2str(Qd)+ " VAR");
                        elseif Qd>=1000 && Qd<1000000
                            app.Q_R.Value=(num2str(Qd/1000)+ " kVAR");
                        elseif Qd>=1000000
                            app.Q_R.Value=(num2str(Qd/1000000)+ " VAR");
                        end
                        F=60;%Frecuencia a 60Hz
                        C=(Qc)/(2*pi*F*(V)^2); %Calculo para la capacidad del condensador en faradios
                        %Comaparador para saber su notacion cientifica con
                        %relacion a su unidad
                        if C >= 1
                            app.CC.Value=(num2str(C)+"F");
                        elseif C >= 0.001
                            app.CC.Value=(num2str(C*1000)+"mF");
                        elseif C >= 0.000001
                            app.CC.Value=(num2str(C*1000000)+"µF");
                        else
                            app.CC.Value=(num2str(C*1000000000)+"nF");
                        end

                    end


                elseif app.TiempoDropDown.Value == '3'%Seleccion del tiempo a evaluar
                    app.NuevodeBonificacinEditField.Enable='on';

                    T=12; %Tiempo equivalente a 12 meses
                    P=app.P.Value;%Potencia demandada
                    FP1=app.FP1.Value; %Factor de potencia promedio
                    FP2=app.FP2.Value; %Factor de potencia ultimo
                    FP3=app.FP3.Value; %Factor de potencia deseado
                    CT=app.CU.Value; %Cargo antes del factor de potencia
                    TH1=acosd(FP1/100);%Angulo de theta1
                    TH2=acosd(FP3/100);%Angulo de theta2


                    Bonx=((1/4)*(1-(90/FP3)))*100;
                    Bonx_rounded=round(Bonx,1);
                    app.NuevodeBonificacinEditField.Value=(num2str(Bonx_rounded)+"%");


                    if FP2<90 &&FP2>0 %Comparando si el ultimo FP es menor a 90% para calcular el banco de capacitores para evitar penalización segun CFE
                        Pen=(3/5*((90/FP2)-1)*100); %calculando penalizacion con el ultimo factor de potencia
                        %Estableciendo los limites de la penalizacion mayor a
                        %2.5%
                        %y menor o igual a 120%
                        if Pen > 2.5 && Pen <= 120
                            % La penalización está dentro del rango deseado
                        else
                            % La penalización está fuera del rango deseado, por lo tanto se establece en el límite superior de 120
                            Pen = min(Pen, 120); % Establecer límite superior en 120%
                        end
                        Pen_rounded = round(Pen,1);
                        app.PoB.Value=("Penalizacion de: "+ num2str(Pen_rounded)+ "%");
                        app.CoD.Value=num2str((Pen_rounded/100)*CT);
                        app.CoB.Value="Cobro de: "+ (app.CoD.Value);
                        Q=(tand(TH1))*(P);
                        Qd=(tand(TH2))*(P);
                        Qc=Q-Qd;


                        %                         app.Q_R_2.Value=(num2str(Q)+" kVAR");
                        %                         app.Q_R.Value=(num2str(Qd)+ " kVAR");
                        app.T1.Value=num2str(TH1+"°");
                        app.T2.Value=num2str(TH2+"°");
                        %Notacion cientifica potencia
                        if app.P.Value>=1 && app.P.Value<1000
                            app.P_A.Value=(num2str(app.P.Value)+ " W");
                        elseif P>=1000
                            app.P_A.Value=(num2str(app.P.Value/1000)+ " KW");
                        end


                        %Comparador cuando Q equivale a VAR o KVAR
                        if Qc>=1 && Qc<1000
                            app.PBC.Value=(num2str(Qc)+" VAR");
                        elseif Qc>=1000 && Qc<1000000
                            app.PBC.Value=(num2str(Qc/1000)+" KVAR");
                        elseif Qc>=1000000
                            app.PBC.Value=(num2str(Qc/1000000)+ "MVAR");

                        end
                        if Q>=1 && Q<1000
                            app.Q_R_2.Value=(num2str(Q)+" VAR");
                        elseif Q>=1000 && Q<1000000
                            app.Q_R_2.Value=(num2str(Q/1000)+" kVAR");
                        elseif Q>1000000
                            app.Q_R_2.Value=(num2str(Q/1000000)+" MVAR");
                        end
                        if Qd>=1 && Qd<1000
                            app.Q_R.Value=(num2str(Qd)+ " VAR");
                        elseif Qd>=1000 && Qd<1000000
                            app.Q_R.Value=(num2str(Qd/1000)+ " kVAR");
                        elseif Qd>=1000000
                            app.Q_R.Value=(num2str(Qd/1000000)+ " VAR");
                        end
                        F=60; %Frecuencia a 60Hz
                        C=(Qc)/(2*pi*F*(V)^2);
                        %Comaparador para saber su notacion cientifica con
                        %relacion a su unidad
                        if C >= 1
                            app.CC.Value=(num2str(C)+"F");
                        elseif C >= 0.001
                            app.CC.Value=(num2str(C*1000)+"mF");
                        elseif C >= 0.000001
                            app.CC.Value=(num2str(C*1000000)+"µF");
                        else
                            app.CC.Value=(num2str(C*1000000000)+"nF");
                        end

                    elseif FP2>=90 && FP2<=100
                        Bon= (1/4*(1-(90/FP2))*100);
                        Bon = max(Bon, 0); % Establecer límite inferior en 0%
                        Bon = min(Bon, 2.5); % Establecer límite superior en 2.5%
                        Bon_rounded=round(Bon,1);

                        app.PoB.Value=("Bonificacion de: "+ num2str((Bon_rounded))+ "%");
                        CoD=num2str(Bon_rounded*CT/100);

                        app.CoB.Value=("Descuento de: "+(CoD)+"-")
                        Q=(tand(TH1))*(P/12);
                        Qd=(tand(TH2))*(P/12);
                        Qc=Q-Qd;

                        %                            app.Q_R_2.Value=(num2str(Q)+" kVAR");
                        %                         app.Q_R.Value=(num2str(Qd)+ " kVAR");
                        app.T1.Value=num2str(TH1+"°");
                        app.T2.Value=num2str(TH2+"°");
                        if app.P.Value>=1 && app.P.Value<1000
                            app.P_A.Value=(num2str(app.P.Value)+ " W");
                        elseif P>=1000
                            app.P_A.Value=(num2str(app.P.Value/1000)+ " KW");
                        end


                        %Comparador cuando Q equivale a VAR o KVAR
                        if Qc>=1 && Qc<1000
                            app.PBC.Value=(num2str(Qc)+" VAR");
                        elseif Qc>=1000 && Qc<1000000
                            app.PBC.Value=(num2str(Qc/1000)+" KVAR");
                        elseif Qc>=1000000
                            app.PBC.Value=(num2str(Qc/1000000)+ "MVAR");

                        end
                        if Q>=1 && Q<1000
                            app.Q_R_2.Value=(num2str(Q)+" VAR");
                        elseif Q>=1000 && Q<1000000
                            app.Q_R_2.Value=(num2str(Q/1000)+" kVAR");
                        elseif Q>1000000
                            app.Q_R_2.Value=(num2str(Q/1000000)+" MVAR");
                        end
                        if Qd>=1 && Qd<1000
                            app.Q_R.Value=(num2str(Qd)+ " VAR");
                        elseif Qd>=1000 && Qd<1000000
                            app.Q_R.Value=(num2str(Qd/1000)+ " kVAR");
                        elseif Qd>=1000000
                            app.Q_R.Value=(num2str(Qd/1000000)+ " VAR");
                        end
                        F=60;%Frecuencia a 60Hz
                        C=(Qc)/(2*pi*F*(V)^2); %Calculo para la capacidad del condensador en faradios
                        %Comaparador para saber su notacion cientifica con
                        %relacion a su unidad
                        if C >= 1
                            app.CC.Value=(num2str(C)+"F");
                        elseif C >= 0.001
                            app.CC.Value=(num2str(C*1000)+"mF");
                        elseif C >= 0.000001
                            app.CC.Value=(num2str(C*1000000)+"µF");
                        else
                            app.CC.Value=(num2str(C*1000000000)+"nF");
                        end

                    end


                end


            end


        end

        % Value changed function: BorrarButton
        function BorrarButtonValueChanged(app, event)
            %Boton para borrar todo
            value = app.BorrarButton.Value;
            app.FP1.Value = double([0]);
            app.FP2.Value = double([0]);
            app.NuevodeBonificacinEditField.Value='';
            app.FP3.Value = double([0]);
            app.VoltajeInstaladoDropDown.Value = app.VoltajeInstaladoDropDown.Items{1};
            app.CU.Value = double([0]);;
            app.CC.Value='';
            app.P.Value=double([0])
            app.PBC.Value='';
            app.CoB.Value='';
            app.PoB.Value='';
            app.T_I.Value='';
            app.C_B_C.Value
            app.TiempoDropDown.Enable="off";
            app.TiempoDropDown.Value='1';


        end

        % Value changed function: BuscarButton
        function BuscarButtonValueChanged(app, event)
            value = app.BuscarButton.Value;
            potencia = string(app.PBC.Value);
            voltaje = app.VoltajeInstaladoDropDown.Value;
            % Verificar si falta alguno de los datos
            %     if isempty(app.PBC.Value) || isempty(app.VoltajeInstaladoDropDown.Value)
            %         msgbox('Ingrese los datos faltantes', 'Error', 'error');
            %         return
            %     end
            %comparador con el dropdown de voltaje para realizar la busqueda
            if strcmp(voltaje, '240V')
                busqueda = sprintf("Banco de capacitores de 240V a %s", potencia);
                url = sprintf("https://www.google.com/search?q=%s", urldecode(busqueda));
            elseif strcmp(voltaje, '480V')
                busqueda = sprintf("Banco de capacitores de 480V a %s", potencia);
                url = sprintf("https://www.google.com/search?q=%s", urldecode(busqueda));
            else
                %mensaje de error en caso de que no se seleccione un
                %voltaje de la lista
                msgbox('Seleccione un voltaje válido', 'Error', 'error');
                return
            end

            try
                web(url);
            catch
                msgbox(sprintf("Error al abrir la URL: %s", ME.message), "Error", "error");
            end
        end

        % Button pushed function: CalcularRetornodeInversionButton
        function CalcularRetornodeInversionButtonPushed(app, event)
            FPd = app.FP2.Value

            if FPd < 90
                Pen= ((3/5)*((90/FPd)-1))
                Des = app.CU.Value*Pen
                gasto = str2double(app.C_B_C.Value)
                mes = gasto / Des
                app.T_I.Value = num2str(mes) + " meses";

            elseif FPd > 90
                FP=app.FP3.Value
                Bon=((1/4)*(1-(90/FPd)))*100
                Bon_rounded=round(Bon,1)
                Bon1=Bon_rounded/10
                Des= app.CU.Value*Bon1
                gasto = str2double(app.C_B_C.Value)
                mes = gasto / Des
                app.T_I.Value = num2str(mes) + " meses";
            end

        end

        % Callback function
        function TiempoDropDownValueChanged(app, event)

        end

        % Value changed function: VoltajeInstaladoDropDown
        function VoltajeInstaladoDropDownValueChanged(app, event)
            value = app.VoltajeInstaladoDropDown.Value;

            if strcmp(value, '240V') || strcmp(value, '480V')
                app.TiempoDropDown.Enable = true;
            else
                app.TiempoDropDown.Enable = false;
                app.P.Enable="off";
                app.FP2.Enable="off";
                app.FP3.Enable="off";
                app.CU.Enable="off";
                app.FP1.Enable = "off";
            end
        end

        % Value changed function: TiempoDropDown
        function TiempoDropDownValueChanged2(app, event)

            value = app.TiempoDropDown.Value;
            if strcmp(value,'2')
                app.P.Enable="on";
                app.FP2.Enable="on";
                app.FP3.Enable="on";
                app.CU.Enable="on";
                app.FP1.Enable = "off";
            elseif strcmp(value, '3')
                app.FP1.Enable = "on";
                app.P.Enable="on";
                app.FP2.Enable="on";
                app.FP3.Enable="on";
                app.CU.Enable="on";
            elseif strcmp(value,'1')
                app.FP1.Enable = "off";
                app.P.Enable="off";
                app.FP2.Enable="off";
                app.FP3.Enable="off";
                app.CU.Enable="off";


            end

        end

        % Callback function
        function VoltajeInstaladoDropDownClicked(app, event)
            item = event.InteractionInformation.Item;

        end

        % Button pushed function: HELPButton
        function HELPButtonPushed(app, event)
            f = msgbox(["*En primer lugar, es necesario ingresar el voltaje de la residencia o industria"; "*Después de ingresar el voltaje, se selecciona si se desea calcular en base a un promedio anual o solo del último mes"; "*Enseguida se ingresa la potencia demandada de la residencia"; "*En caso de que el período para calcular el banco de capacitores sea anual, se activará la casilla de factor de potencia promedio anual o solo del último mes"; "*Se ingresa el valor del factor de potencia deseado"; "*Y por último, se ingresa el monto de penalización o bonificación"; "*En caso de que se desee estimar el tiempo de inversión de un banco de capacitores, se ingresa el precio del banco de capacitores cotizado"; "*Si se necesita ayuda para encontrar un banco de capacitores, se puede utilizar el botón de buscar"],"Cómo utilizar la interfaz","help");

        end

        % Button pushed function: AnalizadorButton
        function AnalizadorButtonPushed(app, event)
                    newApp = TRANSFORMADA;

            % Hace visible la figura de la nueva interfaz
            newApp.UIFigure.Visible = 'on';

            % Espera hasta que se cierre la nueva interfaz antes de continuar
            % uiwait(newApp.UIFigure);
            delete(app.UIFigure);
        end

        % Callback function
        function VisualizadorButtonPushed(app, event)
                    newApp = Visualizar_datos;

            % Hace visible la figura de la nueva interfaz
            newApp.UIFigure.Visible = 'on';

            % Espera hasta que se cierre la nueva interfaz antes de continuar
            % uiwait(newApp.UIFigure);
            delete(app.UIFigure);
        end

        % Button pushed function: SistemadeTierrasButton
        function SistemadeTierrasButtonPushed(app, event)
              newApp = Sistema_de_tierras;

            % Hace visible la figura de la nueva interfaz
            newApp.UIFigure.Visible = 'on';

            % Espera hasta que se cierre la nueva interfaz antes de continuar
            % uiwait(newApp.UIFigure);
            delete(app.UIFigure);
        end

        % Button pushed function: SalirButton
        function SalirButtonPushed(app, event)
             delete(app.UIFigure);
        end

        % Button pushed function: SistemafotovoltaicoButton
        function SistemafotovoltaicoButtonPushed(app, event)
                       newApp = Paneles_solares;

            % Hace visible la figura de la nueva interfaz
            newApp.UIFigure.Visible = 'on';

            % Espera hasta que se cierre la nueva interfaz antes de continuar
            % uiwait(newApp.UIFigure);
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalcularButton = uibutton(app.UIFigure, 'state');
            app.CalcularButton.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonValueChanged, true);
            app.CalcularButton.Text = 'Calcular';
            app.BorrarButton = uibutton(app.UIFigure, 'state');
            app.BorrarButton.ValueChangedFcn = createCallbackFcn(app, @BorrarButtonValueChanged, true);
            app.BorrarButton.Text = 'Borrar';
            app.BuscarButton = uibutton(app.UIFigure, 'state');
            app.BuscarButton.ValueChangedFcn = createCallbackFcn(app, @BuscarButtonValueChanged, true);
            app.BuscarButton.Text = 'Buscar';
            app.R_I = uipanel(app.UIFigure);
            app.R_I.Title = 'Retorno de Inversión';
            app.IngreseCostodelBancodeCondensadoresLabel = uilabel(app.R_I);
            app.IngreseCostodelBancodeCondensadoresLabel.Text = {'Ingrese Costo '; 'del Banco de Condensadores'};
            app.C_B_C = uieditfield(app.R_I, 'text');
            app.TiempodeRetornoInversinEditFieldLabel = uilabel(app.R_I);
            app.TiempodeRetornoInversinEditFieldLabel.Text = 'Tiempo de Retorno Inversión';
            app.T_I = uieditfield(app.R_I, 'text');
            app.T_I.Editable = 'off';
            app.CalcularRetornodeInversionButton = uibutton(app.R_I, 'push');
            app.CalcularRetornodeInversionButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularRetornodeInversionButtonPushed, true);
            app.CalcularRetornodeInversionButton.Text = {'Calcular Retorno '; 'de Inversion'};
            app.AyudaPanel = uipanel(app.UIFigure);
            app.AyudaPanel.Title = '¡Ayuda!';
            app.HELPButton = uibutton(app.AyudaPanel, 'push');
            app.HELPButton.ButtonPushedFcn = createCallbackFcn(app, @HELPButtonPushed, true);
            app.HELPButton.Text = '¡HELP!';
            app.ClculoparaseleccionarelbancodecapacitoresPanel = uipanel(app.UIFigure);
            app.ClculoparaseleccionarelbancodecapacitoresPanel.Title = 'Cálculo para seleccionar el banco de capacitores';
            app.P = uieditfield(app.ClculoparaseleccionarelbancodecapacitoresPanel, 'numeric');
            app.P.ValueDisplayFormat = '%.2f';
            app.VoltajeInstaladoDropDownLabel = uilabel(app.ClculoparaseleccionarelbancodecapacitoresPanel);
            app.VoltajeInstaladoDropDownLabel.Text = 'Voltaje Instalado';
            app.VoltajeInstaladoDropDown = uidropdown(app.ClculoparaseleccionarelbancodecapacitoresPanel);
            app.VoltajeInstaladoDropDown.Items = {};
            app.VoltajeInstaladoDropDown.ValueChangedFcn = createCallbackFcn(app, @VoltajeInstaladoDropDownValueChanged, true);
            app.VoltajeInstaladoDropDown.Value = {};
            app.PotenciadelbancodeCapacitoresLabel = uilabel(app.ClculoparaseleccionarelbancodecapacitoresPanel);
            app.PotenciadelbancodeCapacitoresLabel.Text = 'Potencia del banco de Capacitores';
            app.PBC = uitextarea(app.ClculoparaseleccionarelbancodecapacitoresPanel);
            app.PBC.Editable = 'off';
            app.CapacidaddelBandodeCapacitoresLabel = uilabel(app.ClculoparaseleccionarelbancodecapacitoresPanel);
            app.CapacidaddelBandodeCapacitoresLabel.Text = 'Capacidad del Bando de Capacitores';
            app.CC = uitextarea(app.ClculoparaseleccionarelbancodecapacitoresPanel);
            app.CC.Editable = 'off';
            app.FactordePotencia1aoLabel = uilabel(app.ClculoparaseleccionarelbancodecapacitoresPanel);
            app.FactordePotencia1aoLabel.Text = ' Factor de Potencia (% 1 año)';
            app.FP1 = uieditfield(app.ClculoparaseleccionarelbancodecapacitoresPanel, 'numeric');
            app.FP1.Limits = [0 100];
            app.FP1.ValueDisplayFormat = '%.2f';
            app.ltimoFactordePotenciaLabel = uilabel(app.ClculoparaseleccionarelbancodecapacitoresPanel);
            app.ltimoFactordePotenciaLabel.Text = 'Último  Factor de Potencia (%)';
            app.FP2 = uieditfield(app.ClculoparaseleccionarelbancodecapacitoresPanel, 'numeric');
            app.FP2.Limits = [0 100];
            app.FP2.ValueDisplayFormat = '%.2f';
            app.FactordePotenciaDeseadoLabel = uilabel(app.ClculoparaseleccionarelbancodecapacitoresPanel);
            app.FactordePotenciaDeseadoLabel.Text = 'Factor de Potencia  Deseado (%)';
            app.FP3 = uieditfield(app.ClculoparaseleccionarelbancodecapacitoresPanel, 'numeric');
            app.FP3.Limits = [0 100];
            app.FP3.ValueDisplayFormat = '%.2f';
            app.CargototalantesdelfactordePotenciaEditFieldLabel = uilabel(app.ClculoparaseleccionarelbancodecapacitoresPanel);
            app.CargototalantesdelfactordePotenciaEditFieldLabel.Text = 'Cargo total antes del factor de Potencia';
            app.CU = uieditfield(app.ClculoparaseleccionarelbancodecapacitoresPanel, 'numeric');
            app.CU.ValueDisplayFormat = '%.2f';
            app.PenalizacinoBonificacinLabel = uilabel(app.ClculoparaseleccionarelbancodecapacitoresPanel);
            app.PenalizacinoBonificacinLabel.Text = 'Penalización o Bonificación';
            app.PoB = uitextarea(app.ClculoparaseleccionarelbancodecapacitoresPanel);
            app.PoB.Editable = 'off';
            app.CobrooDescuentoTextAreaLabel = uilabel(app.ClculoparaseleccionarelbancodecapacitoresPanel);
            app.CobrooDescuentoTextAreaLabel.Text = 'Cobro o Descuento';
            app.CoB = uitextarea(app.ClculoparaseleccionarelbancodecapacitoresPanel);
            app.CoB.Editable = 'off';
            app.PeriodoaConsiderarLabel = uilabel(app.ClculoparaseleccionarelbancodecapacitoresPanel);
            app.PeriodoaConsiderarLabel.Text = 'Periodo a Considerar';
            app.TiempoDropDown = uidropdown(app.ClculoparaseleccionarelbancodecapacitoresPanel);
            app.TiempoDropDown.Items = {};
            app.TiempoDropDown.ItemsData = {'1', '2', '3'};
            app.TiempoDropDown.ValueChangedFcn = createCallbackFcn(app, @TiempoDropDownValueChanged2, true);
            app.TiempoDropDown.Value = {};
            app.PotenciaDemandadaWLabel = uilabel(app.ClculoparaseleccionarelbancodecapacitoresPanel);
            app.PotenciaDemandadaWLabel.Text = 'Potencia Demandada (W)';
            app.ResultadosLabel = uilabel(app.ClculoparaseleccionarelbancodecapacitoresPanel);
            app.ResultadosLabel.Text = 'Resultados';
            app.NuevodeBonificacinEditFieldLabel = uilabel(app.ClculoparaseleccionarelbancodecapacitoresPanel);
            app.NuevodeBonificacinEditFieldLabel.Text = '% Nuevo de Bonificación';
            app.NuevodeBonificacinEditField = uieditfield(app.ClculoparaseleccionarelbancodecapacitoresPanel, 'text');
            app.NuevodeBonificacinEditField.Editable = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = 'Imagen7.png';
            app.P_A = uieditfield(app.Panel, 'text');
            app.P_A.Editable = 'off';
            app.Q_R_2 = uieditfield(app.Panel, 'text');
            app.Q_R_2.Editable = 'off';
            app.Q_R = uieditfield(app.Panel, 'text');
            app.Q_R.Editable = 'off';
            app.T2 = uieditfield(app.Panel, 'text');
            app.T2.Editable = 'off';
            app.T1 = uieditfield(app.Panel, 'text');
            app.T1.Editable = 'off';
            app.TotalLabel = uilabel(app.Panel);
            app.TotalLabel.Text = 'Total';
            app.CompensadaLabel = uilabel(app.Panel);
            app.CompensadaLabel.Text = 'Compensada';
            app.Panel_2 = uipanel(app.UIFigure);
            app.AnalizadorButton = uibutton(app.Panel_2, 'push');
            app.AnalizadorButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizadorButtonPushed, true);
            app.AnalizadorButton.Text = 'Analizador ';
            app.SistemadeTierrasButton = uibutton(app.Panel_2, 'push');
            app.SistemadeTierrasButton.ButtonPushedFcn = createCallbackFcn(app, @SistemadeTierrasButtonPushed, true);
            app.SistemadeTierrasButton.Text = 'Sistema de Tierras';
            app.SalirButton = uibutton(app.Panel_2, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @SalirButtonPushed, true);
            app.SalirButton.Text = 'Salir';
            app.SistemafotovoltaicoButton = uibutton(app.Panel_2, 'push');
            app.SistemafotovoltaicoButton.ButtonPushedFcn = createCallbackFcn(app, @SistemafotovoltaicoButtonPushed, true);
            app.SistemafotovoltaicoButton.Text = 'Sistema fotovoltaico';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Diagnostico_energetico

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end