classdef Paneles_solares < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SistemasFotovoltaicosPanel      matlab.ui.container.Panel
        AnalizadorButton                matlab.ui.control.Button
        SistemadeTierrasButton          matlab.ui.control.Button
        CorreccinFPButton               matlab.ui.control.Button
        CalculodelInversorPanel         matlab.ui.container.Panel
        ArreglorecomendadoPanel         matlab.ui.container.Panel
        PanelesenSerieEditField         matlab.ui.control.NumericEditField
        PanelesenSerieEditFieldLabel    matlab.ui.control.Label
        PanelesenParaleloEditField      matlab.ui.control.NumericEditField
        PanelesenParaleloEditFieldLabel  matlab.ui.control.Label
        CalcularButton_4                matlab.ui.control.Button
        ICC                             matlab.ui.control.NumericEditField
        CorrientePanelCortoCircuitoICCDCLabel  matlab.ui.control.Label
        C_Inv                           matlab.ui.control.NumericEditField
        CorrientedelInversorIDCEditFieldLabel  matlab.ui.control.Label
        VoltajedelInversorVDC           matlab.ui.control.NumericEditField
        VoltajedelInversorVDCEditFieldLabel  matlab.ui.control.Label
        VOC                             matlab.ui.control.NumericEditField
        VoltajePanelCircuitoAbiertoVOCDCLabel  matlab.ui.control.Label
        VoltajeaInstalarDropDown        matlab.ui.control.DropDown
        VoltajeaInstalarDropDownLabel   matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        BorrarButton_5                  matlab.ui.control.Button
        CalcularButton_3                matlab.ui.control.Button
        CantidaddePanelesquesoportaelInversorseleccionadoEditField  matlab.ui.control.NumericEditField
        CantidaddePanelesquesoportaelInversorseleccionadoEditFieldLabel  matlab.ui.control.Label
        PotenciadeInversorSeleccionado  matlab.ui.control.NumericEditField
        PotenciadeInversorSeleccionadoWEditFieldLabel  matlab.ui.control.Label
        BorrarButton_4                  matlab.ui.control.Button
        DimensionamientodePanelesSolares  matlab.ui.container.Panel
        PotenciadelInversorrecomendadoWEditField  matlab.ui.control.NumericEditField
        PotenciadelInversorrecomendadoWEditFieldLabel  matlab.ui.control.Label
        HoraSolarPico                   matlab.ui.control.NumericEditField
        HoraSolarPicoEditFieldLabel     matlab.ui.control.Label
        BorrarButton_3                  matlab.ui.control.Button
        HSP_CHKBOX                      matlab.ui.control.CheckBox
        CantidaddePanelesSolaresEditField  matlab.ui.control.NumericEditField
        CantidaddePanelesSolaresEditFieldLabel  matlab.ui.control.Label
        PeriodoBimestralDropDown        matlab.ui.control.DropDown
        PeriodoBimestralDropDownLabel   matlab.ui.control.Label
        PW                              matlab.ui.control.NumericEditField
        PotenciadelPanelWLabel          matlab.ui.control.Label
        AyudaButton                     matlab.ui.control.Button
        P_C_KW                          matlab.ui.control.NumericEditField
        PotenciaconsumidakWhLabel       matlab.ui.control.Label
        UbicacinEstadoDropDown          matlab.ui.control.DropDown
        UbicacinEstadoDropDownLabel     matlab.ui.control.Label
        TipodeInstalacinDropDown        matlab.ui.control.DropDown
        TipodeInstalacinDropDownLabel   matlab.ui.control.Label
        CalcularButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        Property % Description
        C %NI IDEA
        BIMESTRE %PERIODO
        CHK %Checkbox para habilitar la entrada de HSP
        HSP %Hora Solar Pico
        P_Inversor %Potencia del inversor
        N_paneles %Numero de paneles
        P_W %Potencia del Panel
        Vinv %Voltaje a instalar del inversor
        C_D %Consumo diario
        VDC_SIST %Voltaje DC del sistema para las baterias y controlador
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PeriodoBimestralDropDown.Enable="off";
            app.VoltajeaInstalarDropDown.Enable="off";
            app.UbicacinEstadoDropDown.Enable="off";
            % app.ClculodeBateriasPanel.Enable='off';
            app.P_C_KW.Enable="off";
            app.PW.Enable='off';
            app.CantidaddePanelesSolaresEditField.Editable="off";
            app.CalculodelInversorPanel.Enable = 'off';
            app.HSP_CHKBOX.Enable="off";
            app.ICC.Enable="off";
            app.C_Inv.Enable="off";
            app.VoltajedelInversorVDC.Enable="off";
            app.VOC.Enable="off";


            % Mensaje de inicio
            app.UIFigure.Visible = 'off'; % Ocultar la figura principal de la aplicación

            inicioMensaje = sprintf(['¡Bienvenido a la aplicación de Dimensionamiento Fotovoltaico!\n\n'...
                'Esta interfaz proporciona aproximaciones de cálculo para el dimensionamiento fotovoltaico requerido.\n'...
                'Ten en cuenta que los resultados son aproximados y pueden variar según múltiples factores.\n'...
                'Si necesitas una mayor precisión, se recomienda obtener el valor de la Hora Solar Pico (HSP) desde la página:\n'...
                'https://www.re-explorer.org/\n']);

            f = msgbox(inicioMensaje, 'Inicio');
            uiwait(f); % Esperar a que se cierre el cuadro de diálogo antes de continuar
            app.UIFigure.Visible = 'on'; % Mostrar la figura principal de la aplicación


        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            ESTADO = app.UbicacinEstadoDropDown.Value; % UBICACION
            app.BIMESTRE=app.PeriodoBimestralDropDown.Value;
            app.P_W = app.PW.Value; % POTENCIA DEL PANEL
            P_Consumida = app.P_C_KW.Value; % POTENCIA CONSUMIDA
            % estadoNumero = str2double(ESTADO);
            % Verificar si P_Consumida tiene un valor
            if isempty(app.P_C_KW.Value)
                msgbox('Advertencia: La potencia consumida no puede estar vacía.');
                return;
            end
            app.CHK;
            if app.CHK==1
                app.HSP=app.HoraSolarPico.Value;
            else
                switch ESTADO
                    case 'Aguascalientes'
                        app.HSP = 5.5;
                    case 'Baja California'
                        app.HSP = 5.6;
                    case 'Baja California Sur'
                        app.HSP = 5.6;
                    case 'Campeche'
                        app.HSP = 4.8;
                    case 'Chiapas'
                        app.HSP = 4.8;
                    case 'Chihuahua'
                        app.HSP= 5.6;
                    case 'Coahuila'
                        app.HSP= 5.3;
                    case 'Colima'
                        app.HSP = 5;
                    case 'Durango'
                        app.HSP= 5.6;
                    case 'Estado de México'
                        app.HSP= 5.1;
                    case 'Guanajuato'
                        app.HSP= 5.2;
                    case 'Guerrero'
                        app.HSP = 5.2;
                    case 'Hidalgo'
                        app.HSP = 4.8;
                    case 'Jalisco'
                        app.HSP= 5.2;
                    case 'Michoacán'
                        app.HSP = 5.2;
                    case 'Morelos'
                        app.HSP = 5.2;
                    case 'Nayarit'
                        app.HSP= 5.2;
                    case 'Nuevo León'
                        app.HSP= 4.8;
                    case 'Oaxaca'
                        app.HSP = 5.6;
                    case 'Puebla'
                        app.HSP = 4.9;
                    case 'Querétaro'
                        app.HSP = 5;
                    case 'Quintana Roo'
                        app.HSP = 5;
                    case 'San Luis Potosí'
                        app.HSP = 5;
                    case 'Sinaloa'
                        app.HSP= 5.2;
                    case 'Sonora'
                        app.HSP= 5.6;
                    case 'Tabasco'
                        app.HSP = 4.8;
                    case 'Tamaulipas'
                        app.HSP = 4.8;
                    case 'Tlaxcala'
                        app.HSP = 4.8;
                    case 'Veracruz'
                        app.HSP = 4.8;
                    case 'Yucatán'
                        app.HSP= 5;
                    case 'Zacatecas'
                        app.HSP = 5.5;
                    otherwise
                        msgbox('Advertencia: Estado no reconocido.');
                        return;

                end
            end


            % Verificar si P_W o HSP son vacíos o nulos
            if isempty(app.P_W) || isempty(app.HSP)
                msgbox('Advertencia: Algunos valores son nulos o vacíos.');
                return;
            else
                % CALCULO CONSUMO DIARIO PANEL
                kWH_dia = app.P_W * app.HSP / 1000;
            end
            kWH_dia;

            % Verificar si kWH_dia es diferente de cero
            if kWH_dia == 0
                msgbox('Advertencia: El valor de kWH_dia es cero.');
                return;
            end
            app.BIMESTRE

            if app.BIMESTRE==2
                C_P = P_Consumida;
                app.C_D = C_P / 60; % se divide entre 60 para obtener el consumo diario
            elseif app.BIMESTRE==3
                C_P = P_Consumida / 2;
                app.C_D = C_P / 60; % se divide entre 60 para obtener el consumo diario
            elseif app.BIMESTRE==4
                C_P = P_Consumida / 3;
                app.C_D = C_P / 60; % se divide entre 60 para obtener el consumo diario
            elseif app.BIMESTRE==5
                C_P = P_Consumida / 4;
                app.C_D = C_P / 60; % se divide entre 60 para obtener el consumo diario
            elseif app.BIMESTRE==6
                C_P = P_Consumida / 5;
                app.C_D = C_P / 60; % se divide entre 60 para obtener el consumo diario
            elseif app.BIMESTRE==7
                C_P = P_Consumida / 6;
                app.C_D = C_P / 60; % se divide entre 60 para obtener el consumo diario
            else
                msgbox("No se ha seleccionado ningun BIMESTRE.");
            end
            % C_P;
            app.C_D%  Consumo diario


            % Verificar si C_P es nulo o vacío
            if isempty(C_P) || isempty(app.C_D)
                msgbox('Advertencia: Algunos valores son nulos o vacíos.');
                return;
            end
            app.N_paneles = app.C_D / kWH_dia;


            % Verificar si N_paneles es nulo antes de asignarlo
            if isempty(app.N_paneles)
                msgbox('Advertencia: El cálculo de N_paneles resultó en un valor nulo.');
                return;
            end

            % Actualizar el control en la interfaz
            app.CantidaddePanelesSolaresEditField.Value = round(app.N_paneles);
            %Potencia del inversor
            app.P_Inversor=app.P_W*app.N_paneles;
            app.PotenciadelInversorrecomendadoWEditField.Value=round(app.P_Inversor);


        end

        % Value changed function: TipodeInstalacinDropDown
        function TipodeInstalacinDropDownValueChanged(app, event)
            value = app.TipodeInstalacinDropDown.Value;

            % Deshabilitar todo por defecto

            % Habilitar según el tipo seleccionado
            if strcmp(value, 'Interconectado')
                app.PeriodoBimestralDropDown.Enable = 'on';
                app.VoltajeaInstalarDropDown.Enable = 'on';
                app.CalculodelInversorPanel.Enable = 'on';
                app.UbicacinEstadoDropDown.Enable='on'
                app.PW.Enable='on';
                app.P_C_KW.Enable='on';
                app.HSP_CHKBOX.Enable="on";
                app.ICC.Enable="on";
                app.C_Inv.Enable="on";
                app.VoltajedelInversorVDC.Enable="on";
                app.VOC.Enable="on";


            else
                app.PeriodoBimestralDropDown.Enable = 'off';
                app.UbicacinEstadoDropDown.Enable="off";
                app.VoltajeaInstalarDropDown.Enable = 'off';
                app.P_C_KW.Enable = 'off';
                app.PW.Enable = 'off';
                % app.ClculodeBateriasPanel.Enable = 'off';
                app.CalculodelInversorPanel.Enable = 'off';
                app.PW.Enable='off';
                app.P_C_KW.Enable='off';
                app.HSP_CHKBOX.Enable="off";
                app.ICC.Enable="off";
                app.C_Inv.Enable="off";
                app.VoltajedelInversorVDC.Enable="off";
                app.VOC.Enable="off";


            end
        end

        % Value changed function: PeriodoBimestralDropDown
        function PeriodoBimestralDropDownValueChanged(app, event)
            app.BIMESTRE=app.PeriodoBimestralDropDown.Value;

            %añadir un if par el caso de que sea interconectado


        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            % Mensaje de ayuda
            ayudaMensaje = sprintf(['Bienvenido a la aplicación de paneles solares.\n\n'...
                'Instrucciones:\n'...
                '1. Selecciona el estado desde el menú desplegable.\n'...
                '2. Ingresa la potencia nominal del panel solar en el campo correspondiente.\n'...
                '3. Ingresa el consumo bimestral total en el campo correspondiente.\n'...
                '4. Selecciona el periodo bimestral desde el menú desplegable.\n'...
                '5. Presiona el botón "Calcular" para obtener la cantidad de paneles solares.\n'...
                '\nNota: Asegúrate de ingresar datos válidos para obtener resultados precisos.']);

            % Mostrar cuadro de diálogo de ayuda
            msgbox(ayudaMensaje, 'Ayuda', 'help');
        end

        % Value changed function: HSP_CHKBOX
        function HSP_CHKBOXValueChanged(app, event)
            app.CHK = app.HSP_CHKBOX.Value;
            if app.CHK == 1
                app.UbicacinEstadoDropDown.Enable = 'off';
                app.UbicacinEstadoDropDown.Visible = 'off';
                app.HoraSolarPico.Enable = 'on';
                app.HoraSolarPico.Visible = 'on';
                app.HoraSolarPicoEditFieldLabel.Visible="on";
                app.HoraSolarPicoEditFieldLabel.Enable="on";
                app.HoraSolarPico.Editable="on";
                app.UbicacinEstadoDropDownLabel.Enable="off";
                app.UbicacinEstadoDropDownLabel.Visible="off";
                app.HSP = app.HoraSolarPico.Value;
            else
                app.UbicacinEstadoDropDown.Enable = 'on';
                app.UbicacinEstadoDropDown.Visible = 'on';
                app.UbicacinEstadoDropDownLabel.Enable="on";
                app.UbicacinEstadoDropDownLabel.Visible="on";
                app.HoraSolarPico.Enable = 'off';
                app.HoraSolarPico.Visible = 'off';
                app.HoraSolarPicoEditFieldLabel.Visible = 'off';
                app.HoraSolarPicoEditFieldLabel.Enable = 'off';
                app.HoraSolarPico.Editable = 'off';
            end

        end

        % Button pushed function: CalcularButton_3
        function CalcularButton_3Pushed(app, event)
            %Verificando que la entrada de la potencia del inversor no este
            %vacia
            if isempty(app.PotenciadeInversorSeleccionado.Value)
                msgbox("Ingrese la Potencia en Watts del Inversor Seleccionado.");
            else
                app.CantidaddePanelesquesoportaelInversorseleccionadoEditField.Value = round(app.PotenciadeInversorSeleccionado.Value / app.P_W);
            end

        end

        % Value changed function: VoltajeaInstalarDropDown
        function VoltajeaInstalarDropDownValueChanged(app, event)
            app.Vinv=app.VoltajeaInstalarDropDown.Value;

        end

        % Button pushed function: BorrarButton_4
        function BorrarButton_4Pushed(app, event)
app.VOC.Value=0;
app.PanelesenParaleloEditField.Value=0;
app.PanelesenSerieEditField.Value=0;
app.ICC.Value=0;
app.C_Inv.Value=0;
app.VoltajedelInversorVDC.Value=0;
app.VoltajeaInstalarDropDown.ItemsData=[1];
        end

        % Button pushed function: BorrarButton_3
        function BorrarButton_3Pushed(app, event)

        end

        % Callback function
        function CalcularButton_2Pushed(app, event)


        end

        % Callback function
        function VDC_SIS_BATValueChanged(app, event)

        end

        % Value changed function: UbicacinEstadoDropDown
        function UbicacinEstadoDropDownValueChanged(app, event)
            value = app.UbicacinEstadoDropDown.Value;

        end

        % Button pushed function: CalcularButton_4
        function CalcularButton_4Pushed(app, event)

            % Calcula la potencia máxima del panel solar
            panelPower = app.VOC.Value * app.ICC.Value;
            InversorPower=app.VoltajedelInversorVDC.Value*app.C_Inv.Value;

            % Verifica si la potencia máxima no coincide con la potencia deseada
                    % Calcula la cantidad de paneles en serie
                    app.PanelesenSerieEditField.Value = round(app.VoltajedelInversorVDC.Value / app.VOC.Value);

                    % Calcula la cantidad de paneles en paralelo
                    app.PanelesenParaleloEditField.Value = round(app.C_Inv.Value / app.ICC.Value);
              
        end

        % Button pushed function: AnalizadorButton
        function AnalizadorButtonPushed(app, event)
            newApp = TRANSFORMADA;

            % Hace visible la figura de la nueva interfaz
            newApp.UIFigure.Visible = 'on';

            % Espera hasta que se cierre la nueva interfaz antes de continuar
            % uiwait(newApp.UIFigure);
            delete(app.UIFigure);
        end

        % Button pushed function: CorreccinFPButton
        function CorreccinFPButtonPushed(app, event)
            newApp = Diagnostico_energetico;

            % Hace visible la figura de la nueva interfaz
            newApp.UIFigure.Visible = 'on';

            % Espera hasta que se cierre la nueva interfaz antes de continuar
            % uiwait(newApp.UIFigure);
            delete(app.UIFigure);
        end

        % Button pushed function: SistemadeTierrasButton
        function SistemadeTierrasButtonPushed(app, event)
            newApp = Sistema_de_tierras;

            % Hace visible la figura de la nueva interfaz
            newApp.UIFigure.Visible = 'on';

            % Espera hasta que se cierre la nueva interfaz antes de continuar
            % uiwait(newApp.UIFigure);
            delete(app.UIFigure);
        end

        % Button pushed function: BorrarButton_5
        function BorrarButton_5Pushed(app, event)
            app.CantidaddePanelesquesoportaelInversorseleccionadoEditField.Value=0;
            app.PotenciadeInversorSeleccionado.Value=0;
        end

        % Callback function: not associated with a component
        function BuscarButtonPushed(app, event)

%           potencia = string(app.PotenciadeInversorSeleccionadoWEditField.Value);
% voltaje = app.VoltajeaInstalarDropDown.Value;
% 
% % Verificar que no haya entradas vacías
% if isempty(potencia) || isempty(voltaje)
%     msgbox('Ingrese la potencia y seleccione un voltaje válido', 'Error', 'error');
%     return
% end
% 
% % Comparador con el dropdown de voltaje para realizar la búsqueda
% if strcmp(voltaje, '2')
%     busqueda = sprintf('Inversor solar 110V 1F-2H a %s', potencia);
%     url = sprintf('https://www.google.com/search?q=%s', urlencode(busqueda));
% elseif strcmp(voltaje, '3')
%     busqueda = sprintf('Inversor solar 220V 2F-3H a %s', potencia);
%     url = sprintf('https://www.google.com/search?q=%s', urlencode(busqueda));
% elseif strcmp(voltaje, '4')
%     busqueda = sprintf('Inversor solar 220V 3F-4H a %s', potencia);
%     url = sprintf('https://www.google.com/search?q=%s', urlencode(busqueda));
% elseif strcmp(voltaje, '5')
%     busqueda = sprintf('Inversor solar 440V 3F-4H a %s', potencia);
%     url = sprintf('https://www.google.com/search?q=%s', urlencode(busqueda));
% else
%     % Mensaje de error en caso de que no se seleccione un
%     % voltaje de la lista
%     msgbox('Seleccione un voltaje válido', 'Error', 'error');
%     return
% end
% 
% try
%     web(url);
% catch ME
%     msgbox(sprintf('Error al abrir la URL: %s', ME.message), 'Error', 'error');
% end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SistemasFotovoltaicosPanel = uipanel(app.UIFigure);
            app.SistemasFotovoltaicosPanel.Title = 'Sistemas Fotovoltaicos';
            app.DimensionamientodePanelesSolares = uipanel(app.SistemasFotovoltaicosPanel);
            app.DimensionamientodePanelesSolares.Title = 'Dimensionamiento de Paneles Solares';
            app.CalcularButton = uibutton(app.DimensionamientodePanelesSolares, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.TipodeInstalacinDropDownLabel = uilabel(app.DimensionamientodePanelesSolares);
            app.TipodeInstalacinDropDownLabel.Text = 'Tipo de Instalación';
            app.TipodeInstalacinDropDown = uidropdown(app.DimensionamientodePanelesSolares);
            app.TipodeInstalacinDropDown.Items = {'Seleccione', 'Interconectado', 'Pronto se añadiran más...'};
            app.TipodeInstalacinDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodeInstalacinDropDownValueChanged, true);
            app.TipodeInstalacinDropDown.Value = 'Seleccione';
            app.UbicacinEstadoDropDownLabel = uilabel(app.DimensionamientodePanelesSolares);
            app.UbicacinEstadoDropDownLabel.Text = 'Ubicación (Estado)';
            app.UbicacinEstadoDropDown = uidropdown(app.DimensionamientodePanelesSolares);
            app.UbicacinEstadoDropDown.Items = {'Seleccione', 'Aguascalientes', 'Baja California', 'Baja California Sur', 'Campeche', 'Chiapas', 'Chihuahua', 'Coahuila', 'Colima', 'Durango', 'Estado de México', 'Guanajuato', 'Guerrero', 'Hidalgo', 'Jalisco', 'Michoacán', 'Morelos', 'Nayarit', 'Nuevo León', 'Oaxaca', 'Puebla', 'Querétaro', 'Quintana Roo', 'San Luis Potosí', 'Sinaloa', 'Sonora', 'Tabasco', 'Tamaulipas', 'Tlaxcala', 'Veracruz', 'Yucatán', 'Zacatecas'};
            app.UbicacinEstadoDropDown.ValueChangedFcn = createCallbackFcn(app, @UbicacinEstadoDropDownValueChanged, true);
            app.UbicacinEstadoDropDown.Value = 'Seleccione';
            app.PotenciaconsumidakWhLabel = uilabel(app.DimensionamientodePanelesSolares);
            app.PotenciaconsumidakWhLabel.Text = 'Potencia consumida (kWh)';
            app.P_C_KW = uieditfield(app.DimensionamientodePanelesSolares, 'numeric');
            app.AyudaButton = uibutton(app.DimensionamientodePanelesSolares, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda';
            app.PotenciadelPanelWLabel = uilabel(app.DimensionamientodePanelesSolares);
            app.PotenciadelPanelWLabel.Text = 'Potencia del Panel (W)';
            app.PW = uieditfield(app.DimensionamientodePanelesSolares, 'numeric');
            app.PeriodoBimestralDropDownLabel = uilabel(app.DimensionamientodePanelesSolares);
            app.PeriodoBimestralDropDownLabel.Text = 'Periodo (Bimestral) ';
            app.PeriodoBimestralDropDown = uidropdown(app.DimensionamientodePanelesSolares);
            app.PeriodoBimestralDropDown.Items = {'Seleccione', '1 BIMESTRE', '2 BIMESTRES', '3 BIMESTRES', '4 BIMESTRES', '5 BIMESTRES', '6 BIMESTRES'};
            app.PeriodoBimestralDropDown.ItemsData = [1 2 3 4 5 6 7];
            app.PeriodoBimestralDropDown.ValueChangedFcn = createCallbackFcn(app, @PeriodoBimestralDropDownValueChanged, true);
            app.PeriodoBimestralDropDown.Value = 1;
            app.CantidaddePanelesSolaresEditFieldLabel = uilabel(app.DimensionamientodePanelesSolares);
            app.CantidaddePanelesSolaresEditFieldLabel.Text = 'Cantidad de Paneles Solares';
            app.CantidaddePanelesSolaresEditField = uieditfield(app.DimensionamientodePanelesSolares, 'numeric');
            app.HSP_CHKBOX = uicheckbox(app.DimensionamientodePanelesSolares);
            app.HSP_CHKBOX.ValueChangedFcn = createCallbackFcn(app, @HSP_CHKBOXValueChanged, true);
            app.HSP_CHKBOX.Text = 'Agregar HSP';
            app.BorrarButton_3 = uibutton(app.DimensionamientodePanelesSolares, 'push');
            app.BorrarButton_3.ButtonPushedFcn = createCallbackFcn(app, @BorrarButton_3Pushed, true);
            app.BorrarButton_3.Text = 'Borrar';
            app.HoraSolarPicoEditFieldLabel = uilabel(app.DimensionamientodePanelesSolares);
            app.HoraSolarPicoEditFieldLabel.Enable = 'off';
            app.HoraSolarPicoEditFieldLabel.Visible = 'off';
            app.HoraSolarPicoEditFieldLabel.Text = 'Hora Solar Pico';
            app.HoraSolarPico = uieditfield(app.DimensionamientodePanelesSolares, 'numeric');
            app.HoraSolarPico.Enable = 'off';
            app.HoraSolarPico.Visible = 'off';
            app.PotenciadelInversorrecomendadoWEditFieldLabel = uilabel(app.DimensionamientodePanelesSolares);
            app.PotenciadelInversorrecomendadoWEditFieldLabel.Text = 'Potencia del Inversor recomendado (W)';
            app.PotenciadelInversorrecomendadoWEditField = uieditfield(app.DimensionamientodePanelesSolares, 'numeric');
            app.PotenciadelInversorrecomendadoWEditField.Editable = 'off';
            app.CalculodelInversorPanel = uipanel(app.SistemasFotovoltaicosPanel);
            app.CalculodelInversorPanel.Title = 'Calculo del Inversor';
            app.BorrarButton_4 = uibutton(app.CalculodelInversorPanel, 'push');
            app.BorrarButton_4.ButtonPushedFcn = createCallbackFcn(app, @BorrarButton_4Pushed, true);
            app.BorrarButton_4.Text = 'Borrar';
            app.Panel = uipanel(app.CalculodelInversorPanel);
            app.PotenciadeInversorSeleccionadoWEditFieldLabel = uilabel(app.Panel);
            app.PotenciadeInversorSeleccionadoWEditFieldLabel.Text = 'Potencia de Inversor Seleccionado (W)';
            app.PotenciadeInversorSeleccionado = uieditfield(app.Panel, 'numeric');
            app.CantidaddePanelesquesoportaelInversorseleccionadoEditFieldLabel = uilabel(app.Panel);
            app.CantidaddePanelesquesoportaelInversorseleccionadoEditFieldLabel.Text = 'Cantidad de Paneles que soporta el Inversor seleccionado';
            app.CantidaddePanelesquesoportaelInversorseleccionadoEditField = uieditfield(app.Panel, 'numeric');
            app.CantidaddePanelesquesoportaelInversorseleccionadoEditField.Editable = 'off';
            app.CalcularButton_3 = uibutton(app.Panel, 'push');
            app.CalcularButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_3Pushed, true);
            app.CalcularButton_3.Text = 'Calcular';
            app.BorrarButton_5 = uibutton(app.Panel, 'push');
            app.BorrarButton_5.ButtonPushedFcn = createCallbackFcn(app, @BorrarButton_5Pushed, true);
            app.BorrarButton_5.Text = 'Borrar';
            app.VoltajeaInstalarDropDownLabel = uilabel(app.CalculodelInversorPanel);
            app.VoltajeaInstalarDropDownLabel.Text = 'Voltaje a Instalar';
            app.VoltajeaInstalarDropDown = uidropdown(app.CalculodelInversorPanel);
            app.VoltajeaInstalarDropDown.Items = {'Seleccione', '110V 1F-2H', '220V 2F-3H', '220V 3F-4H', '440V 3F-4H'};
            app.VoltajeaInstalarDropDown.ValueChangedFcn = createCallbackFcn(app, @VoltajeaInstalarDropDownValueChanged, true);
            app.VoltajeaInstalarDropDown.Value = 'Seleccione';
            app.VoltajePanelCircuitoAbiertoVOCDCLabel = uilabel(app.CalculodelInversorPanel);
            app.VoltajePanelCircuitoAbiertoVOCDCLabel.Text = 'Voltaje Panel Circuito Abierto (VOC-DC)';
            app.VOC = uieditfield(app.CalculodelInversorPanel, 'numeric');
            app.VoltajedelInversorVDCEditFieldLabel = uilabel(app.CalculodelInversorPanel);
            app.VoltajedelInversorVDCEditFieldLabel.Text = 'Voltaje del Inversor (VDC)';
            app.VoltajedelInversorVDC = uieditfield(app.CalculodelInversorPanel, 'numeric');
            app.CorrientedelInversorIDCEditFieldLabel = uilabel(app.CalculodelInversorPanel);
            app.CorrientedelInversorIDCEditFieldLabel.Text = 'Corriente del Inversor (IDC)';
            app.C_Inv = uieditfield(app.CalculodelInversorPanel, 'numeric');
            app.CorrientePanelCortoCircuitoICCDCLabel = uilabel(app.CalculodelInversorPanel);
            app.CorrientePanelCortoCircuitoICCDCLabel.Text = 'Corriente Panel Corto Circuito (ICC-DC)';
            app.ICC = uieditfield(app.CalculodelInversorPanel, 'numeric');
            app.CalcularButton_4 = uibutton(app.CalculodelInversorPanel, 'push');
            app.CalcularButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_4Pushed, true);
            app.CalcularButton_4.Text = 'Calcular';
            app.ArreglorecomendadoPanel = uipanel(app.CalculodelInversorPanel);
            app.ArreglorecomendadoPanel.Title = 'Arreglo recomendado';
            app.PanelesenParaleloEditFieldLabel = uilabel(app.ArreglorecomendadoPanel);
            app.PanelesenParaleloEditFieldLabel.Text = 'Paneles en Paralelo';
            app.PanelesenParaleloEditField = uieditfield(app.ArreglorecomendadoPanel, 'numeric');
            app.PanelesenParaleloEditField.Editable = 'off';
            app.PanelesenSerieEditFieldLabel = uilabel(app.ArreglorecomendadoPanel);
            app.PanelesenSerieEditFieldLabel.Text = 'Paneles en Serie';
            app.PanelesenSerieEditField = uieditfield(app.ArreglorecomendadoPanel, 'numeric');
            app.PanelesenSerieEditField.Editable = 'off';
            app.CorreccinFPButton = uibutton(app.SistemasFotovoltaicosPanel, 'push');
            app.CorreccinFPButton.ButtonPushedFcn = createCallbackFcn(app, @CorreccinFPButtonPushed, true);
            app.CorreccinFPButton.Text = 'Corrección F.P';
            app.SistemadeTierrasButton = uibutton(app.SistemasFotovoltaicosPanel, 'push');
            app.SistemadeTierrasButton.ButtonPushedFcn = createCallbackFcn(app, @SistemadeTierrasButtonPushed, true);
            app.SistemadeTierrasButton.Text = 'Sistema de Tierras';
            app.AnalizadorButton = uibutton(app.SistemasFotovoltaicosPanel, 'push');
            app.AnalizadorButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizadorButtonPushed, true);
            app.AnalizadorButton.Text = 'Analizador';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Paneles_solares

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end