classdef Sistema_de_tierras < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel_8                        matlab.ui.container.Panel
        Numconancho                    matlab.ui.control.NumericEditField
        NumconductoresanchoLabel       matlab.ui.control.Label
        Numconlargo                    matlab.ui.control.NumericEditField
        NumconductoreslargoLabel       matlab.ui.control.Label
        MallaLabel                     matlab.ui.control.Label
        Malla                          matlab.ui.control.UIAxes
        Panel_7                        matlab.ui.container.Panel
        SistemafotovoltaicoButton      matlab.ui.control.Button
        AnalizadorButton               matlab.ui.control.Button
        CorreccinFPButton              matlab.ui.control.Button
        SalirButton                    matlab.ui.control.Button
        CargardatosButton              matlab.ui.control.Button
        AyudaButton                    matlab.ui.control.Button
        LimpiarButton                  matlab.ui.control.Button
        CalcularButton                 matlab.ui.control.Button
        Panel_6                        matlab.ui.container.Panel
        correcto                       matlab.ui.control.Label
        DiseocorrectoLabel             matlab.ui.control.Label
        AWGLabel                       matlab.ui.control.Label
        Vtoquecuerpo                   matlab.ui.control.NumericEditField
        Vpasocuerpo                    matlab.ui.control.NumericEditField
        Labletoque                     matlab.ui.control.Label
        Calibrecon                     matlab.ui.control.NumericEditField
        CalibredelconductorEditFieldLabel  matlab.ui.control.Label
        Vtoquetierra                   matlab.ui.control.NumericEditField
        VoltajedetoquecalculadoymedidoLabel  matlab.ui.control.Label
        Vpasotierra                    matlab.ui.control.NumericEditField
        VoltajedepasocalculadoymedidoLabel  matlab.ui.control.Label
        Lablepaso                      matlab.ui.control.Label
        ResultadosLabel                matlab.ui.control.Label
        Panel_5                        matlab.ui.container.Panel
        tiempofalla                    matlab.ui.control.NumericEditField
        DuraaciondelafallaEditFieldLabel  matlab.ui.control.Label
        factdede                       matlab.ui.control.NumericEditField
        FactordedecrementoEditFieldLabel  matlab.ui.control.Label
        factdeinc                      matlab.ui.control.NumericEditField
        FactordeincrementoEditFieldLabel  matlab.ui.control.Label
        SegLabel                       matlab.ui.control.Label
        DatosdeplaneaciondesubestacinLabel  matlab.ui.control.Label
        Panel_4                        matlab.ui.container.Panel
        DropDown                       matlab.ui.control.DropDown
        profundidadgrava               matlab.ui.control.NumericEditField
        ProfundidaddecapadegravaEditFieldLabel  matlab.ui.control.Label
        mLabel_13                      matlab.ui.control.Label
        profdemalla                    matlab.ui.control.NumericEditField
        ProfundidaddelamallaEditFieldLabel  matlab.ui.control.Label
        mLabel_12                      matlab.ui.control.Label
        Lvarillas                      matlab.ui.control.NumericEditField
        LongitudvarillaEditFieldLabel  matlab.ui.control.Label
        mLabel_11                      matlab.ui.control.Label
        PzasLabel                      matlab.ui.control.Label
        Nvarillas                      matlab.ui.control.NumericEditField
        NVarillasEditFieldLabel        matlab.ui.control.Label
        MallaespaciamientoLabel        matlab.ui.control.Label
        DimensionesdelterrenoLabel     matlab.ui.control.Label
        mLabel_10                      matlab.ui.control.Label
        mLabel_9                       matlab.ui.control.Label
        mLabel_8                       matlab.ui.control.Label
        mLabel_7                       matlab.ui.control.Label
        DatosdemalladoLabel            matlab.ui.control.Label
        aterreno                       matlab.ui.control.NumericEditField
        AEditField_2Label              matlab.ui.control.Label
        amalla                         matlab.ui.control.NumericEditField
        AEditFieldLabel                matlab.ui.control.Label
        lterreno                       matlab.ui.control.NumericEditField
        LEditField_2Label              matlab.ui.control.Label
        lmalla                         matlab.ui.control.NumericEditField
        LEditFieldLabel                matlab.ui.control.Label
        Panel_3                        matlab.ui.container.Panel
        areatorres                     matlab.ui.control.NumericEditField
        AreadebasetorreEditFieldLabel  matlab.ui.control.Label
        torresxkm                      matlab.ui.control.NumericEditField
        TorresxkmEditFieldLabel        matlab.ui.control.Label
        Zhilos                         matlab.ui.control.NumericEditField
        ZhilosdeguardaEditFieldLabel   matlab.ui.control.Label
        Nhilos                         matlab.ui.control.NumericEditField
        NhilosdeguardaEditFieldLabel   matlab.ui.control.Label
        m2Label                        matlab.ui.control.Label
        UnidadLabel_2                  matlab.ui.control.Label
        mLabel_4                       matlab.ui.control.Label
        UnidadLabel                    matlab.ui.control.Label
        DatosdetorrescontiguasLabel    matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        Cs                             matlab.ui.control.NumericEditField
        FactordedegradacionCsLabel     matlab.ui.control.Label
        factordf                       matlab.ui.control.NumericEditField
        FactordereflexinkLabel         matlab.ui.control.Label
        segundacapa                    matlab.ui.control.NumericEditField
        SegundacapaEditFieldLabel      matlab.ui.control.Label
        primeracapa                    matlab.ui.control.NumericEditField
        PrimeracapaEditFieldLabel      matlab.ui.control.Label
        VertabladeCsButton             matlab.ui.control.Button
        CalcularkButton                matlab.ui.control.Button
        mLabel_2                       matlab.ui.control.Label
        mLabel                         matlab.ui.control.Label
        ResistividaddelatierrraLabel   matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        kALabel                        matlab.ui.control.Label
        corrienteCC                    matlab.ui.control.NumericEditField
        CorrientedecortocircuitoEditFieldLabel  matlab.ui.control.Label
        kVLabel                        matlab.ui.control.Label
        voltaje                        matlab.ui.control.NumericEditField
        VoltajeLabel                   matlab.ui.control.Label
        DatosdesubestacinLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %%%%%%%%%%%%%%%%%%%%%Codigo para popup informativo%%%%%%%%%%%%%%%%%
            app.UIFigure.Visible = 'off'; % Hide the main app's figure
            f = msgbox("A continuacion se presenta una APP para llevar a cabo el calculo para un sistema de tierras","Bienvenido","help");
            uiwait(f); % Wait for the message box to be closed
            app.UIFigure.Visible = 'on'; % Show the main app's figure


            % Get the screen size
            screenSize = get(groot,'ScreenSize');

            % Get the main app's size
            appSize = app.UIFigure.Position(3:4);

            % Calculate the position of the main app to center it on the screen
            xPos = (screenSize(3) - appSize(1)) / 2;
            yPos = (screenSize(4) - appSize(2)) / 2;

            % Set the position of the main app
            app.UIFigure.Position(1) = xPos;
            app.UIFigure.Position(2) = yPos;

            % Show the main app's figure
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: CalcularkButton
        function CalcularkButtonPushed(app, event)
            PC=app.primeracapa.Value
            SC=app.segundacapa.Value
            app.factordf.Value = ((SC-PC)/(SC+PC));
        end

        % Button pushed function: VertabladeCsButton
        function VertabladeCsButtonPushed(app, event)
            tabla;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
         
        end

        % Button pushed function: CargardatosButton
        function CargardatosButtonPushed(app, event)
            app.voltaje.Value=115;
            app.corrienteCC.Value=9238;
            app.primeracapa.Value=1800;
            app.segundacapa.Value=87.88;
            app.Cs.Value= 0.59;
            app.tiempofalla.Value=0.1;
            app.profundidadgrava.Value=0.1;
            app.lterreno.Value=80;
            app.aterreno.Value=40;
            app.Nhilos.Value=2;
            app.Zhilos.Value=1.8;
            app.torresxkm.Value=3;
            app.areatorres.Value=16;
            app.Nvarillas.Value=15;
            app.Lvarillas.Value=3.05;
            app.profdemalla.Value=1;
            app.factdeinc.Value=1.25;
            app.factdede.Value=1;
            app.lmalla.Value=4;
            app.amalla.Value=4;

        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            %%%%%%%% Material %%%%%%%%%%%%%%%%%%%%
            value = app.DropDown.Value;
            if strcmp (value, 'Cobre suave recocido')
                Conduct=1
                FactA=0.00393;
                K0=234;
                Tm=1083;
                pr=1.7241*10^4;
                TCAP=3.422;
            elseif strcmp (value, 'Cobre duro')
                Conduct=0.97;
                FactA=0.00381;
                K0=242;
                Tm=1084;
                pr=1.7774*10^4;
                TCAP=3.422;
            elseif strcmp (value, 'Cobre con alma de acero 1')
                Conduct=0.40;
                FactA=0.00378;
                K0=245;
                Tm=1084;
                pr=4.397*10^4;
                TCAP=3.846;
            elseif strcmp (value, 'Cobre con alma de acero 2')
                Conduct=0.30;
                FactA=0.00378;
                K0=245;
                Tm=1084;
                pr=5.862*10^4;
                TCAP=3.846;
            elseif strcmp (value, 'Aluminio EC')
                Conduct=0.61;
                FactA=0.00403;
                K0=228;
                Tm=657;
                pr=2.862*10^4;
                TCAP=2.556;
            elseif strcmp (value, 'Aluminio aleación 5005')
                Conduct=0.535;
                FactA=0.00353;
                K0=263;
                Tm=660;
                pr=3.2226*10^4;
                TCAP=2.598;
            elseif strcmp (value, 'Aluminio aleación 6201')
                Conduct=0.525;
                FactA=0.00347;
                K0=268;
                Tm=660;
                pr=3.2840*10^4;
                TCAP=2.598;
            elseif strcmp (value, 'Aluminio con alma de acero')
                Conduct=0.203;
                FactA=0.00360;
                K0=258;
                Tm=660;
                pr=8.4805*10^4;
                TCAP=2.670;
            elseif strcmp (value, 'Aluminio revestido de zinc')
                Conduct=0.085;
                FactA=0.00320;
                K0=293;
                Tm=419;
                pr=20.1*10^4;
                TCAP=3.931;
            elseif strcmp (value, 'Acero inoxidable No. 304')
                Conduct=0.024;
                FactA=0.00130;
                K0=749;
                Tm=1400;
                pr=70.2*10^4;
                TCAP=4.032;

            end


            %%%%%%%%%% Variables %%%%%%%%%%%%%%%%%%
            Amax=app.corrienteCC.Value;
            p1=app.primeracapa.Value;
            p2=app.segundacapa.Value;
            Nt=app.torresxkm.Value;
            Areatorre=app.areatorres.Value;
            Zh=app.Zhilos.Value;
            Nh=app.Nhilos.Value;
            Lterreno=app.lterreno.Value
            Aterreno=app.aterreno.Value
            Lmalla=app.lmalla.Value;
            Amalla=app.amalla.Value;
            Nv=app.Nvarillas.Value;
            Lv=app.Lvarillas.Value;
            h=app.profdemalla.Value
            Ts=app.tiempofalla.Value;
            Cp=app.factdeinc.Value;
            Df=app.factdede.Value;
            Css=app.Cs.Value;
            D=app.amalla.Value;
            %%%%%%%%%  Sistema de tierras cuerpo humano %%%%%%%%%
            Vtoquecuerpo1=(1000+1.5*Css*p1)*(0.116/sqrt(Ts));
            Vpasocuerpo1=(1000+6*Css*p1)*(0.116/sqrt(Ts));


            %%%%%%%%% Resistencia de la torre%%%%%%%%%%%
            Rtorre=(p2)/((Nt*2*pi*(sqrt(Areatorre/pi))));

            %%%%%%%%% Impedancia equivalente %%%%%%%%%%%
            Z=(sqrt(Zh*Rtorre))/Nh;

            %%%%%%%% Numero de conductores %%%%%%%%%%%%
            NumconA=(Lterreno/Lmalla)+1;
            NumconL=(Aterreno/Lmalla)+1;

            %%%%%%%%% Resistencia de la tierra %%%%%%%%%
            L=(NumconL*Lterreno)+(NumconA*Aterreno)+(Nv*Lv);
            A=Lterreno*Aterreno;
            Rg=p2*((1/L)+(1/(sqrt(20*A)))*(1+(1/(1+h*(sqrt(20/A))))));

            %%%%%%%%% Valor RMS de la corriente %%%%%%%%
            Ig=(Z/(Z+Rg))*(Amax/1000);

            %%%%%%%%% Corriente Maxima de la red %%%%%%%
            IG=Cp*Df*Ig;
            IG0=IG*1000;
            %%%%%%%%% Calculo del area del conductor minimo de tierra %%%%%%
            Amm=IG*(sqrt(((Ts*FactA*pr)/TCAP)/(log(1+((Tm-40)/(K0+40))))));

            if Amm > 0 && Amm < 0.85
                AWG = 18;
            elseif Amm > 0.85 && Amm < 1.29
                AWG = 16;
            elseif Amm > 1.29 && Amm < 2.08
                AWG = 14;
            elseif Amm > 2.08 && Amm < 3.29
                AWG = 12;
            elseif Amm > 3.29 && Amm < 5.29
                AWG = 10
            elseif Amm > 5.29 && Amm < 8.32
                AWG = 8
            elseif Amm > 8.32 && Amm < 13.29
                AWG = 6
            elseif Amm > 13.29 && Amm < 21.15
                AWG = 4
            elseif Amm > 21.15 && Amm < 33.62
                AWG = 2;
            elseif Amm > 33.62 && Amm < 53.48
                AWG = 0;
            end

            %%%%%%% Diametro del conductor de la malla %%%%%%%%

            if AWG == 18
                d=0.0010240;
            elseif AWG == 16
                d=0.00129;
            elseif AWG == 14
                d=0.00163;
            elseif AWG == 12
                d=0.00205;
            elseif AWG == 10
                d=0.00259;
            elseif AWG == 8
                d=0.00326;
            elseif AWG == 6
                d=0.00411;
            elseif AWG == 4
                d=0.00519;
            elseif AWG == 2
                d=0.00654;
            elseif AWG == 0
                d=0.00825;
            end

            if AWG > 6
                d=0.00411;
                AWG=6;
            end

            %%%%%%%% Sistema de tierras comprobación %%%%%%%%

            %%%% Producto de factores geometricos %%%%%
            n=sqrt(NumconL*NumconA)
            Km = (1/(2*pi)) * (log((D^2/(16*h*d))+((D+2*h)^2/(8*D*d))-(h/(4*d))) + (1/sqrt(1+(h/1))) * log(8/(pi*((2*n)-1))));

            %%%%%% Factor de correción para Vtoque %%%%%%%
            Ki=0.656+(0.172*n);

            %%%%% Largototal de conductores de malla %%%%%%%
            Lc=(NumconA*Aterreno)+(NumconL*Lterreno);
            %%%%% Largo total de varillas en malla %%%%%%%
            Lr=Nv*Lv;

            %%%% Voltaje toque tierra %%%%%
            Vtoquetierra1=(p2*Km*Ki*IG0)/(Lc+1.15*Lr);

            %%%% Voltaje paso tierra %%%%%%
            if NumconL>NumconA
                x=NumconL;
            elseif NumconA>NumconL
                x=NumconA;
            end
            Ks = (1/pi) * ((1/(2*h)) + (1/(D+h)) + ((1/D) * (1-(0.5^(x-2)))));

            Kipaso=0.656+(0.172*x);

            Vpasotierra1=(p2*Ks*Kipaso*IG0)/(Lc+1.15*Lr);

            %%%%% Vaciar resultados en los edit texts %%%%%%
            app.Vpasotierra.Value=Vpasotierra1;
            app.Vpasocuerpo.Value=Vpasocuerpo1;
            app.Vtoquecuerpo.Value=Vtoquecuerpo1;
            app.Vtoquetierra.Value=Vtoquetierra1;
            app.Calibrecon.Value=AWG;

            if Vpasotierra1<Vpasocuerpo1
                app.Lablepaso.Text='<';
            elseif Vpasotierra1<Vpasocuerpo1
                app.Lablepaso.Text='>';
            end

            if Vtoquetierra1<Vtoquecuerpo1
                app.Labletoque.Text='<';
            elseif Vtoquetierra1>Vtoquecuerpo1
                app.Labletoque.Text='>';
            end


            if Vtoquetierra1<Vtoquecuerpo1 & Vpasotierra1<Vpasocuerpo1
                app.correcto.Text='Si';
            elseif Vtoquetierra1>Vtoquecuerpo1 || Vpasotierra1>Vpasocuerpo1
                app.correcto.Text='No';
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% grafica%%%%%%%
            % Número de cuadros a lo largo y a lo ancho
            filas = NumconA;
            columnas = NumconL;

            % Crear una figura
            set(gcf, 'Visible', 'off');

            % Bucle para dibujar los cuadros blancos con líneas rojas
            for i = 1:filas
                for j = 1:columnas
                    % Calcular las coordenadas de los vértices del cuadro
                    x = [i-1, i, i, i-1];
                    y = [j-1, j-1, j, j];

                    % Dibujar el cuadro blanco con líneas rojas
                    patch(x, y, 'w', 'EdgeColor', 'r');
                end
            end

            % Establecer los límites del eje para ajustar el tamaño del tablero
            axis equal;
            axis([0 filas 0 columnas]);


            % Añadir título al eje x
            xlabel('Numero de conductores a lo largo');

            % Añadir título al eje y
            ylabel('Numero de conductores a lo ancho');


            % Obtener los datos de la gráfica generada
            ax = gca;  % Obtener el objeto axes actual
            h = ax.Children;  % Obtener los objetos hijos del axes

            % Copiar la gráfica generada al objeto axes de la app
            copyobj(h, app.Malla);

            % Mostrar el objeto axes en la app
            app.Malla.Visible = 'on';


            app.Numconancho.Value=NumconL;
            app.Numconlargo.Value=NumconA;
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            app.voltaje.Value=double([0]);
            app.corrienteCC.Value=double([0]);
            app.primeracapa.Value=double([0]);
            app.segundacapa.Value=double([0]);
            app.factordf.Value=double([0]);
            app.Cs.Value=double([0]);
            app.Nhilos.Value=double([0]);
            app.Zhilos.Value=double([0]);
            app.torresxkm.Value=double([0]);
            app.areatorres.Value=double([0]);
            app.aterreno.Value=double([0]);
            app.lterreno.Value=double([0]);
            app.amalla.Value=double([0]);
            app.lmalla.Value=double([0]);
            app.Nvarillas.Value=double([0]);
            app.Lvarillas.Value=double([0]);
            app.profdemalla.Value=double([0]);
            app.profundidadgrava.Value=double([0]);
            app.factdede.Value=double([0]);
            app.factdeinc.Value=double([0]);
            app.tiempofalla.Value=double([0]);
            app.Vpasocuerpo.Value=double([0]);
            app.Vpasotierra.Value=double([0]);
            app.Vtoquecuerpo.Value=double([0]);
            app.Vtoquetierra.Value=double([0]);
            app.Calibrecon.Value=double([0]);

            app.correcto.Text='¿?';

            app.DropDown.Value=app.DropDown.Items{1};
        end

        % Button pushed function: SalirButton
        function SalirButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            msgbox("Para utilizar esta aplicació","Ayuda","help");
        end

        % Button pushed function: AnalizadorButton
        function AnalizadorButtonPushed(app, event)
               newApp = TRANSFORMADA;

            % Hace visible la figura de la nueva interfaz
            newApp.UIFigure.Visible = 'on';

            % Espera hasta que se cierre la nueva interfaz antes de continuar
            % uiwait(newApp.UIFigure);
            delete(app.UIFigure);
        end

        % Button pushed function: CorreccinFPButton
        function CorreccinFPButtonPushed(app, event)
               newApp = Diagnostico_energetico;

            % Hace visible la figura de la nueva interfaz
            newApp.UIFigure.Visible = 'on';

            % Espera hasta que se cierre la nueva interfaz antes de continuar
            % uiwait(newApp.UIFigure);
            delete(app.UIFigure);
        end

        % Button pushed function: SistemafotovoltaicoButton
        function SistemafotovoltaicoButtonPushed(app, event)
                         newApp = Paneles_solares;

            % Hace visible la figura de la nueva interfaz
            newApp.UIFigure.Visible = 'on';

            % Espera hasta que se cierre la nueva interfaz antes de continuar
            % uiwait(newApp.UIFigure);
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.DatosdesubestacinLabel = uilabel(app.Panel);
            app.DatosdesubestacinLabel.Text = 'Datos de subestación';
            app.VoltajeLabel = uilabel(app.Panel);
            app.VoltajeLabel.Text = 'Voltaje';
            app.voltaje = uieditfield(app.Panel, 'numeric');
            app.kVLabel = uilabel(app.Panel);
            app.kVLabel.Text = 'kV';
            app.CorrientedecortocircuitoEditFieldLabel = uilabel(app.Panel);
            app.CorrientedecortocircuitoEditFieldLabel.Text = 'Corriente de corto circuito ';
            app.corrienteCC = uieditfield(app.Panel, 'numeric');
            app.kALabel = uilabel(app.Panel);
            app.kALabel.Text = 'kA';
            app.Panel_2 = uipanel(app.UIFigure);
            app.ResistividaddelatierrraLabel = uilabel(app.Panel_2);
            app.ResistividaddelatierrraLabel.Text = 'Resistividad de la tierrra ';
            app.mLabel = uilabel(app.Panel_2);
            app.mLabel.Text = '(Ω-m)';
            app.mLabel_2 = uilabel(app.Panel_2);
            app.mLabel_2.Text = '(Ω-m)';
            app.CalcularkButton = uibutton(app.Panel_2, 'push');
            app.CalcularkButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularkButtonPushed, true);
            app.CalcularkButton.Text = 'Calcular "k"';
            app.VertabladeCsButton = uibutton(app.Panel_2, 'push');
            app.VertabladeCsButton.ButtonPushedFcn = createCallbackFcn(app, @VertabladeCsButtonPushed, true);
            app.VertabladeCsButton.Text = 'Ver tabla de Cs';
            app.PrimeracapaEditFieldLabel = uilabel(app.Panel_2);
            app.PrimeracapaEditFieldLabel.Text = 'Primera capa';
            app.primeracapa = uieditfield(app.Panel_2, 'numeric');
            app.SegundacapaEditFieldLabel = uilabel(app.Panel_2);
            app.SegundacapaEditFieldLabel.Text = 'Segunda capa';
            app.segundacapa = uieditfield(app.Panel_2, 'numeric');
            app.FactordereflexinkLabel = uilabel(app.Panel_2);
            app.FactordereflexinkLabel.Text = {'Factor de '; 'reflexión "k"'};
            app.factordf = uieditfield(app.Panel_2, 'numeric');
            app.FactordedegradacionCsLabel = uilabel(app.Panel_2);
            app.FactordedegradacionCsLabel.Text = {'Factor de '; 'degradacion (Cs)'};
            app.Cs = uieditfield(app.Panel_2, 'numeric');
            app.Panel_3 = uipanel(app.UIFigure);
            app.DatosdetorrescontiguasLabel = uilabel(app.Panel_3);
            app.DatosdetorrescontiguasLabel.Text = 'Datos de torres contiguas';
            app.UnidadLabel = uilabel(app.Panel_3);
            app.UnidadLabel.Text = 'Unidad';
            app.mLabel_4 = uilabel(app.Panel_3);
            app.mLabel_4.Text = '(Ω-m)';
            app.UnidadLabel_2 = uilabel(app.Panel_3);
            app.UnidadLabel_2.Text = 'Unidad';
            app.m2Label = uilabel(app.Panel_3);
            app.m2Label.Text = 'm^2';
            app.NhilosdeguardaEditFieldLabel = uilabel(app.Panel_3);
            app.NhilosdeguardaEditFieldLabel.Text = 'N hilos de guarda';
            app.Nhilos = uieditfield(app.Panel_3, 'numeric');
            app.ZhilosdeguardaEditFieldLabel = uilabel(app.Panel_3);
            app.ZhilosdeguardaEditFieldLabel.Text = '"Z" hilos de guarda';
            app.Zhilos = uieditfield(app.Panel_3, 'numeric');
            app.TorresxkmEditFieldLabel = uilabel(app.Panel_3);
            app.TorresxkmEditFieldLabel.Text = 'Torres x km';
            app.torresxkm = uieditfield(app.Panel_3, 'numeric');
            app.AreadebasetorreEditFieldLabel = uilabel(app.Panel_3);
            app.AreadebasetorreEditFieldLabel.Text = 'Area de base torre';
            app.areatorres = uieditfield(app.Panel_3, 'numeric');
            app.Panel_4 = uipanel(app.UIFigure);
            app.LEditFieldLabel = uilabel(app.Panel_4);
            app.LEditFieldLabel.Text = 'L=';
            app.lmalla = uieditfield(app.Panel_4, 'numeric');
            app.LEditField_2Label = uilabel(app.Panel_4);
            app.LEditField_2Label.Text = 'L=';
            app.lterreno = uieditfield(app.Panel_4, 'numeric');
            app.AEditFieldLabel = uilabel(app.Panel_4);
            app.AEditFieldLabel.Text = 'A=';
            app.amalla = uieditfield(app.Panel_4, 'numeric');
            app.AEditField_2Label = uilabel(app.Panel_4);
            app.AEditField_2Label.Text = 'A=';
            app.aterreno = uieditfield(app.Panel_4, 'numeric');
            app.DatosdemalladoLabel = uilabel(app.Panel_4);
            app.DatosdemalladoLabel.Text = 'Datosde mallado';
            app.mLabel_7 = uilabel(app.Panel_4);
            app.mLabel_7.Text = 'm';
            app.mLabel_8 = uilabel(app.Panel_4);
            app.mLabel_8.Text = 'm';
            app.mLabel_9 = uilabel(app.Panel_4);
            app.mLabel_9.Text = 'm';
            app.mLabel_10 = uilabel(app.Panel_4);
            app.mLabel_10.Text = 'm';
            app.DimensionesdelterrenoLabel = uilabel(app.Panel_4);
            app.DimensionesdelterrenoLabel.Text = 'Dimensiones del terreno:';
            app.MallaespaciamientoLabel = uilabel(app.Panel_4);
            app.MallaespaciamientoLabel.Text = 'Malla espaciamiento:';
            app.NVarillasEditFieldLabel = uilabel(app.Panel_4);
            app.NVarillasEditFieldLabel.Text = 'N Varillas';
            app.Nvarillas = uieditfield(app.Panel_4, 'numeric');
            app.PzasLabel = uilabel(app.Panel_4);
            app.PzasLabel.Text = 'Pzas';
            app.mLabel_11 = uilabel(app.Panel_4);
            app.mLabel_11.Text = 'm';
            app.LongitudvarillaEditFieldLabel = uilabel(app.Panel_4);
            app.LongitudvarillaEditFieldLabel.Text = 'Longitud varilla';
            app.Lvarillas = uieditfield(app.Panel_4, 'numeric');
            app.mLabel_12 = uilabel(app.Panel_4);
            app.mLabel_12.Text = 'm';
            app.ProfundidaddelamallaEditFieldLabel = uilabel(app.Panel_4);
            app.ProfundidaddelamallaEditFieldLabel.Text = 'Profundidad de la malla';
            app.profdemalla = uieditfield(app.Panel_4, 'numeric');
            app.mLabel_13 = uilabel(app.Panel_4);
            app.mLabel_13.Text = 'm';
            app.ProfundidaddecapadegravaEditFieldLabel = uilabel(app.Panel_4);
            app.ProfundidaddecapadegravaEditFieldLabel.Text = 'Profundidad de capa de grava';
            app.profundidadgrava = uieditfield(app.Panel_4, 'numeric');
            app.DropDown = uidropdown(app.Panel_4);
            app.DropDown.Items = {'Material del conductor', 'Cobre suave recocido', 'Cobre duro', 'Cobre con alma de acero 1', 'Cobre con alma de acero 2', 'Aluminio EC', 'Aluminio aleación 5005', 'Aluminio aleación 6201', 'Aluminio con alma de acero', 'Acero revestido de zinc', 'Acero inoxidable No. 304', ''};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Material del conductor';
            app.Panel_5 = uipanel(app.UIFigure);
            app.DatosdeplaneaciondesubestacinLabel = uilabel(app.Panel_5);
            app.DatosdeplaneaciondesubestacinLabel.Text = 'Datos de planeacion de subestación';
            app.SegLabel = uilabel(app.Panel_5);
            app.SegLabel.Text = 'Seg.';
            app.FactordeincrementoEditFieldLabel = uilabel(app.Panel_5);
            app.FactordeincrementoEditFieldLabel.Text = 'Factor de incremento';
            app.factdeinc = uieditfield(app.Panel_5, 'numeric');
            app.FactordedecrementoEditFieldLabel = uilabel(app.Panel_5);
            app.FactordedecrementoEditFieldLabel.Text = 'Factor de decremento ';
            app.factdede = uieditfield(app.Panel_5, 'numeric');
            app.DuraaciondelafallaEditFieldLabel = uilabel(app.Panel_5);
            app.DuraaciondelafallaEditFieldLabel.Text = 'Duraacion de la falla';
            app.tiempofalla = uieditfield(app.Panel_5, 'numeric');
            app.Panel_6 = uipanel(app.UIFigure);
            app.ResultadosLabel = uilabel(app.Panel_6);
            app.ResultadosLabel.Text = 'Resultados';
            app.Lablepaso = uilabel(app.Panel_6);
            app.Lablepaso.Text = '>';
            app.VoltajedepasocalculadoymedidoLabel = uilabel(app.Panel_6);
            app.VoltajedepasocalculadoymedidoLabel.Text = {'Voltaje de paso '; 'calculado y medido'};
            app.Vpasotierra = uieditfield(app.Panel_6, 'numeric');
            app.VoltajedetoquecalculadoymedidoLabel = uilabel(app.Panel_6);
            app.VoltajedetoquecalculadoymedidoLabel.Text = {'Voltaje de toque '; 'calculado y medido '};
            app.Vtoquetierra = uieditfield(app.Panel_6, 'numeric');
            app.CalibredelconductorEditFieldLabel = uilabel(app.Panel_6);
            app.CalibredelconductorEditFieldLabel.Text = 'Calibre del conductor ';
            app.Calibrecon = uieditfield(app.Panel_6, 'numeric');
            app.Labletoque = uilabel(app.Panel_6);
            app.Labletoque.Text = '>';
            app.Vpasocuerpo = uieditfield(app.Panel_6, 'numeric');
            app.Vtoquecuerpo = uieditfield(app.Panel_6, 'numeric');
            app.AWGLabel = uilabel(app.Panel_6);
            app.AWGLabel.Text = 'AWG';
            app.DiseocorrectoLabel = uilabel(app.Panel_6);
            app.DiseocorrectoLabel.Text = '¿Diseño correcto?';
            app.correcto = uilabel(app.Panel_6);
            app.correcto.Text = '¿?';
            app.Panel_7 = uipanel(app.UIFigure);
            app.CalcularButton = uibutton(app.Panel_7, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.LimpiarButton = uibutton(app.Panel_7, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.AyudaButton = uibutton(app.Panel_7, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda';
            app.CargardatosButton = uibutton(app.Panel_7, 'push');
            app.CargardatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargardatosButtonPushed, true);
            app.CargardatosButton.Text = 'Cargar datos';
            app.SalirButton = uibutton(app.Panel_7, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @SalirButtonPushed, true);
            app.SalirButton.Text = 'Salir';
            app.CorreccinFPButton = uibutton(app.Panel_7, 'push');
            app.CorreccinFPButton.ButtonPushedFcn = createCallbackFcn(app, @CorreccinFPButtonPushed, true);
            app.CorreccinFPButton.Text = 'Corrección F.P';
            app.AnalizadorButton = uibutton(app.Panel_7, 'push');
            app.AnalizadorButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizadorButtonPushed, true);
            app.AnalizadorButton.Text = 'Analizador';
            app.SistemafotovoltaicoButton = uibutton(app.Panel_7, 'push');
            app.SistemafotovoltaicoButton.ButtonPushedFcn = createCallbackFcn(app, @SistemafotovoltaicoButtonPushed, true);
            app.SistemafotovoltaicoButton.Text = 'Sistema fotovoltaico';
            app.Panel_8 = uipanel(app.UIFigure);
            app.Malla = uiaxes(app.Panel_8);
            ylabel(app.Malla, {''; ''})
            app.MallaLabel = uilabel(app.Panel_8);
            app.MallaLabel.Text = 'Malla';
            app.NumconductoreslargoLabel = uilabel(app.Panel_8);
            app.NumconductoreslargoLabel.Text = 'Num. conductores (largo)';
            app.Numconlargo = uieditfield(app.Panel_8, 'numeric');
            app.NumconductoresanchoLabel = uilabel(app.Panel_8);
            app.NumconductoresanchoLabel.Text = {'Num. '; 'conductores '; 'ancho'};
            app.Numconancho = uieditfield(app.Panel_8, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sistema_de_tierras

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end