classdef TRANSFORMADA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel_7                        matlab.ui.container.Panel
        SistemafotovoltaicoButton      matlab.ui.control.Button
        SalirButton                    matlab.ui.control.Button
        SistemadeTierrasButton         matlab.ui.control.Button
        CorreccinFPButton              matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        Panel_5                        matlab.ui.container.Panel
        Panel_6                        matlab.ui.container.Panel
        UIAxes2_2                      matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        HELPButton                     matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        Borrar                         matlab.ui.control.Button
        CalcularButton                 matlab.ui.control.Button
        ArchivoButton                  matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        THDI                           matlab.ui.control.NumericEditField
        THDVLabel_2                    matlab.ui.control.Label
        PotenciaactivaW                matlab.ui.control.NumericEditField
        THDVLabel                      matlab.ui.control.Label
        THDV                           matlab.ui.control.NumericEditField
        PotenciaactivaWLabel           matlab.ui.control.Label
        FP                             matlab.ui.control.NumericEditField
        PotenciaaparenteVA             matlab.ui.control.NumericEditField
        FPEditFieldLabel               matlab.ui.control.Label
        PotenciaaparenteVALabel        matlab.ui.control.Label
        PotenciadedistorsionVAD        matlab.ui.control.NumericEditField
        CorrienteRMS                   matlab.ui.control.NumericEditField
        PotenciadedistorsionkVADLabel  matlab.ui.control.Label
        CorrienteRMSkALabel            matlab.ui.control.Label
        PotenciareactivaVAR            matlab.ui.control.NumericEditField
        VoltajeRMS                     matlab.ui.control.NumericEditField
        PotenciareactivakVARLabel      matlab.ui.control.Label
        VoltajeRMSkVLabel              matlab.ui.control.Label
        Panel_3                        matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        Property % Description
        archivopath
        ruta_archivo
        Vm
        Im
        Vk
        Ik

    end

    methods (Access = public)

        function results = func(app)

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.THDI.Editable="off";
            app.THDV.Editable="off";
            app.FP.Editable="off";
            app.PotenciaactivaW.Editable="off";
            app.PotenciaaparenteVA.Editable="off";
            app.PotenciareactivaVAR.Editable="off";
            app.VoltajeRMS.Editable="off";
            app.CorrienteRMS.Editable="off";
            app.PotenciadedistorsionVAD.Editable="off";
            title(app.UIAxes, 'Armónicos para el Voltaje');
            title(app.UIAxes2, 'Armónicos para la Corriente');
            title(app.UIAxes_2,'Voltaje');
            title(app.UIAxes2_2,'Corriente');
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes2_2);
            cla(app.UIAxes_2);
            xlabel(app.UIAxes2_2,'Tiempo (T)');
            ylabel(app.UIAxes2_2,'Corriente (A)');
            xlabel(app.UIAxes_2,'Tiempo (T)');
            ylabel(app.UIAxes_2,'Voltaje (V)');
            xlabel(app.UIAxes,'Armónico');
            ylabel(app.UIAxes,'THDV (%)');
            xlabel(app.UIAxes2,'Armónico');
            ylabel(app.UIAxes2,'THDI (%) ');


        end

        % Button pushed function: CalcularButton
        function BorrarButtonPushed(app, event)
           
% PROCESAMIENTO DE ARCHIVO
            if isempty(app.ruta_archivo) % Verifica si se ha seleccionado un archivo
                msgbox('Seleccione un archivo', 'Error');
                return
            end

            [~, ~, extension] = fileparts(app.ruta_archivo);

            % Cargar los datos del archivo
            [x, y] = loadData(app.ruta_archivo, extension);


            N = length(x);
            N2 = length(y);

            Fw = zeros(N, 1);
            Fw2 = zeros(N2, 1);
            Vk = zeros(N, 1);
            Ik = zeros(N2, 1);


            if isnumeric(x) && isvector(x) && isnumeric(y) && isvector(y)
                for k = 0:N-1
                    Fw(k+1) = 0;
                    Fw2(k+1) = 0;

                    for n = 0:N-1
                        Fw(k+1) = Fw(k+1) + x(n+1) * exp((-1j * k * n * 2 * pi )/ N);
                        Fw2(k+1) = Fw2(k+1) + y(n+1) * exp((-1j * k * n * 2 * pi) / N2);
                    end

                    Fw(k+1) = Fw(k+1) / N;
                    Fw2(k+1) = Fw2(k+1) / N2;
                end

                Vk = Fw;
                Ik = Fw2;
            else
                msgbox('Los vectores de entrada no son numéricos o no son vectores.','Error');
            end
            Vk;
            Ik;

            %Calculo para obtener THD
            %Longitud Voltaje inicial
            n1 = length(Vk);


            % Corriente inicial
            n2 = length(Ik);

            % Valores absolutos
            c1 = abs(Vk);
            c2 = abs(Ik);

            % Cálculo RMS para el voltaje
            Vrms = 0;
            for a = 1:n1
                Vrms = Vrms + c1(a)^2;
            end
            Vrms = sqrt(Vrms);
             app.VoltajeRMS.Value= Vrms;

            % Cálculo RMS para la corriente
            Irms = 0;
            for i = 1:n2
                Irms = Irms + c2(i)^2;
            end
            Irms = sqrt(Irms);
            app.CorrienteRMS.Value= Irms;
     
            % Cálculo de la potencia aparente
            S = Irms * Vrms;
            app.PotenciaaparenteVA.Value=S;


            CKV = conj(Vk);
            CIK = conj(Ik);

            %Potencia activa (W)
            P = 0;
            for l = 1:n1
                P = P + (Vk(l) * conj(Ik(l)));
            end
            P;
            app.PotenciaactivaW.Value= abs(P);

            %Potencia reactiva (VAR)
            Q = 0;
            for h = 1:n2
                Q = Q + (c1(h))^2 *(c2(h))^2-((Vk(h))^2*conj(Ik(h))^2);

            end
            Q=sqrt(Q);
            app.PotenciareactivaVAR.Value= abs(Q);

            %Potencia de distorsión
            D=sqrt(S^2-P^2-Q^2);
            app.PotenciadedistorsionVAD.Value=abs(D);

            %Factor de potencia(FP)

            FP = P / S; % Cálculo del factor de potencia FP

            % Limitar FP al rango [0, 1]
            FP = min(max(FP, 0), 1);

            app.FP.Value = abs([FP]); % Asignar el valor de FP a app.FP.Value

            %Distorsión Armónica Total "THD"
           THDV = 0;
            THDI = 0;
            for b = 3:(n1/2)
                THDV = THDV + c1(b)^2;
            end
            THDV = sqrt(THDV) / c1(2) * 100; % Calcular THDV

            for b = 3:(n2/2)
                THDI = THDI + c2(b)^2;
            end
            THDI = sqrt(THDI) / c2(2) * 100; % Calcular THDI

            app.THDV.Value = abs(THDV);
            app.THDI.Value = abs(THDI);

            %GRÁFICA DE BARRAS PARA ARMÓNICOS
            % Convertir los valores de Vk e Ik a porcentajes
            Vm = (abs(Vk) / abs(Vk(2))) * 100;
            Im = (abs(Ik) / abs(Ik(2))) * 100;

            % Crear vector de armónicos|
            % Crear vector de armónicos
            % armonicos = 0:(n1/2);
            armonicos = 0:(40/2);

            % Crear la gráfica de barras para armónicos
            bar(app.UIAxes, armonicos(3:end), Vm(3:length(armonicos)));
            xlabel(app.UIAxes, 'Armónico');
            ylabel(app.UIAxes, 'THDV (%)');
            title(app.UIAxes, 'Armónicos para el Voltaje');
            grid(app.UIAxes, 'on');
            % Autoescalado en el eje y
            ylim(app.UIAxes, 'auto');

            % Etiquetas de los armónicos en el eje X
            xticks(app.UIAxes, armonicos(3:end));
            xticklabels(app.UIAxes, cellstr(num2str(armonicos')));
            xtickangle(app.UIAxes, 90);

            bar(app.UIAxes2, armonicos(3:end), Im(3:length(armonicos)));
            xlabel(app.UIAxes2, 'Armónico');
            ylabel(app.UIAxes2, 'THDI (%)');
            title(app.UIAxes2, 'Armónicos para la Corriente');
            grid(app.UIAxes2, 'on');
            % Autoescalado en el eje y
            ylim(app.UIAxes2, 'auto');

            % Etiquetas de los armónicos en el eje X
            xticks(app.UIAxes2, armonicos(3:end));
            xticklabels(app.UIAxes2, cellstr(num2str(armonicos')));
            xtickangle(app.UIAxes2, 90);


            	function [x, y] = loadData(filePath, extension)
                x = double([]); % Asignación inicial de vectores vacíos
                y = double([]);
                switch lower(extension)
                    case '.xlsx'
                        % Lee el archivo Excel
                        data = readtable(filePath);
                        x1= data{22:8192,1};%Tiempo
                        x = data{22:8192, 2}; % Primera columna como vector x (voltaje)
                        y = data{22:8192, 3}; % Segunda columna como vector y (corriente)
                        %Configuracion grafica para voltaje
                        plot(app.UIAxes_2, x1, x,'b');
                        xtitle(app.UIAxes_2,'Tiempo (T)');
                        ytitle(app.UIAxes_2,'Voltaje (V)');


                        %Configuracion gráfica para tiempo
                        plot(app.UIAxes2_2, x1, y, 'r');
                        xtitle(app.UIAxes2_2,'Tiempo (T)');
                        ytitle(app.UIAxes2_2,'Corriente (A)');

                    case '.csv'
                        % Lee el archivo CSV
                        data = readtable(filePath);
                        ch1= data{22:8192,1}; %Tiempo
                        ch2 = data{22:8192,2}; % Voltaje
                        ch3 = data{22:8192,3}; % Corriente

                        x = double(ch2); % Voltaje
                        x1= double(ch1); %Tiempo
                        y = double(ch3); %Corriente
                        %Configuracion grafica para voltaje
                        plot(app.UIAxes_2, x1, x,'b');
                        xlabel(app.UIAxes_2,'Tiempo (T)');
                        ylabel(app.UIAxes_2,'Voltaje (V)');


                        %Configuracion gráfica para tiempo
                        plot(app.UIAxes2_2, x1, y, 'r');
                        xlabel(app.UIAxes2_2,'Tiempo (T)');
                        ylabel(app.UIAxes2_2,'Corriente (A)');
                    case '.xls'
                        % Lee el archivo Excel
                        data = readtable(filePath);
                        x1= data{22:8192,1}; %Primera columna como vector de tiempo
                        x = data{22:8192, 2}; % Segunda columna como vector x (voltaje)
                        y = data{22:8192, 3}; % Tercera  columna como vector y (corriente)
                        %Configuracion grafica para voltaje
                        plot(app.UIAxes_2, x1, x,'b');
                        xlabel(app.UIAxes_2,'Tiempo (T)');
                        ylabel(app.UIAxes_2,'Voltaje (V)');


                        %Configuracion gráfica para tiempo
                        plot(app.UIAxes2_2, x1, y, 'r');
                        xlabel(app.UIAxes2_2,'Tiempo (T)');
                        ylabel(app.UIAxes2_2,'Corriente (A)');;


                    otherwise
                        msgbox('Formato de archivo no válido', 'Error');
                        return
                end
                end

        end

        % Button pushed function: ArchivoButton
        function ArchivoButtonPushed(app, event)
            % SELECCIÓN Y LECTURA DE ARCHIVO
            tipos = {'*.csv;*.xlsx;*.xls'};
            [file, path] = uigetfile(tipos);
            if isequal(file, 0)
                msgbox('Selección cancelada', 'Selección de archivo');
            else
                app.ruta_archivo = fullfile(path, file); % Guarda la ruta del archivo en la propiedad app.ruta_archivo
            end

        end

        % Callback function
        function AnlisisDropDownValueChanged(app, event)

        end

        % Callback function
        function BorrarButtonPushed2(app, event)

        end

        % Button pushed function: Borrar
        function BorrarButtonPushed3(app, event)
            % Borrar gráficas
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes2_2);
            cla(app.UIAxes_2);


            % Restablecer valores de los campos de datos
            app.VoltajeRMS.Value = double([0]);
            app.CorrienteRMS.Value = double([0]);
            app.PotenciaaparenteVA.Value = double([0]);
            app.PotenciaactivaW.Value = double([0]);
            app.PotenciareactivaVAR.Value = double([0]);
            app.PotenciadedistorsionVAD.Value = double([0]);
            app.FP.Value = double([0]);
            app.THDV.Value = double([0]);
            app.THDI.Value = double([0]);


        end

        % Callback function
        function ArchivoButtonPushed2(app, event)

        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            % Obtener el nuevo tamaño de la figura
            newFigureSize = app.UIFigure.Position(3:4);

        end

        % Button pushed function: HELPButton
        function HELPButtonPushed(app, event)
            msg = sprintf('El programa lee archivos en formato xlsx y csv. Solo debes cargar el archivo en cualquiera de los formatos compatibles con la app.\n\nLuego de haber cargado el archivo, presiona el botón de "Calcular" para obtener los resultados de las mediciones cargadas anteriormente. Los resultados incluyen el voltaje rms, corriente rms, potencia activa, reactiva y aparente, distorsión, porcentaje de armónicos en el voltaje y en la corriente, y el factor de potencia.\n\nAdemás, el botón mostrará también el gráfico de los armónicos.\n\nPara borrar todos los datos, simplemente presiona el botón de "Borrar". Esto eliminará los datos de las gráficas y los resultados en los recuadros correspondientes.');

            msgbox(msg, 'Ayuda');
        end

        % Button pushed function: CorreccinFPButton
        function CorreccinFPButtonPushed(app, event)
               newApp = Diagnostico_energetico;

            % Hace visible la figura de la nueva interfaz
            newApp.UIFigure.Visible = 'on';

            % Espera hasta que se cierre la nueva interfaz antes de continuar
            % uiwait(newApp.UIFigure);
            delete(app.UIFigure);
        end

        % Callback function
        function VisualizadorButtonPushed(app, event)
               newApp = Visualizar_datos;

            % Hace visible la figura de la nueva interfaz
            newApp.UIFigure.Visible = 'on';

            % Espera hasta que se cierre la nueva interfaz antes de continuar
            % uiwait(newApp.UIFigure);
            delete(app.UIFigure);
        end

        % Button pushed function: SistemadeTierrasButton
        function SistemadeTierrasButtonPushed(app, event)
                    newApp = Sistema_de_tierras;

            % Hace visible la figura de la nueva interfaz
            newApp.UIFigure.Visible = 'on';

            % Espera hasta que se cierre la nueva interfaz antes de continuar
            % uiwait(newApp.UIFigure);
            delete(app.UIFigure);   
        end

        % Button pushed function: SalirButton
        function SalirButtonPushed(app, event)
             delete(app.UIFigure); 
        end

        % Button pushed function: SistemafotovoltaicoButton
        function SistemafotovoltaicoButtonPushed(app, event)
                       newApp = Paneles_solares;

            % Hace visible la figura de la nueva interfaz
            newApp.UIFigure.Visible = 'on';

            % Espera hasta que se cierre la nueva interfaz antes de continuar
            % uiwait(newApp.UIFigure);
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.Panel_5 = uipanel(app.UIFigure);
            app.Panel_3 = uipanel(app.Panel_5);
            app.UIAxes2 = uiaxes(app.Panel_3);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.Panel_3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.Panel_5);
            app.VoltajeRMSkVLabel = uilabel(app.Panel_2);
            app.VoltajeRMSkVLabel.Text = 'Voltaje RMS (V)';
            app.PotenciareactivakVARLabel = uilabel(app.Panel_2);
            app.PotenciareactivakVARLabel.Text = 'Potencia reactiva (VAR)';
            app.VoltajeRMS = uieditfield(app.Panel_2, 'numeric');
            app.PotenciareactivaVAR = uieditfield(app.Panel_2, 'numeric');
            app.CorrienteRMSkALabel = uilabel(app.Panel_2);
            app.CorrienteRMSkALabel.Text = 'Corriente RMS (A)';
            app.PotenciadedistorsionkVADLabel = uilabel(app.Panel_2);
            app.PotenciadedistorsionkVADLabel.Text = 'Potencia de distorsion (VAD)';
            app.CorrienteRMS = uieditfield(app.Panel_2, 'numeric');
            app.PotenciadedistorsionVAD = uieditfield(app.Panel_2, 'numeric');
            app.PotenciaaparenteVALabel = uilabel(app.Panel_2);
            app.PotenciaaparenteVALabel.Text = 'Potencia aparente (VA)';
            app.FPEditFieldLabel = uilabel(app.Panel_2);
            app.FPEditFieldLabel.Text = 'F.P';
            app.PotenciaaparenteVA = uieditfield(app.Panel_2, 'numeric');
            app.FP = uieditfield(app.Panel_2, 'numeric');
            app.PotenciaactivaWLabel = uilabel(app.Panel_2);
            app.PotenciaactivaWLabel.Text = 'Potencia activa (W)';
            app.THDV = uieditfield(app.Panel_2, 'numeric');
            app.THDVLabel = uilabel(app.Panel_2);
            app.THDVLabel.Text = 'THDV';
            app.PotenciaactivaW = uieditfield(app.Panel_2, 'numeric');
            app.THDVLabel_2 = uilabel(app.Panel_2);
            app.THDVLabel_2.Text = 'THDI';
            app.THDI = uieditfield(app.Panel_2, 'numeric');
            app.Panel = uipanel(app.Panel_5);
            app.ArchivoButton = uibutton(app.Panel, 'push');
            app.ArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @ArchivoButtonPushed, true);
            app.ArchivoButton.Text = 'Archivo';
            app.CalcularButton = uibutton(app.Panel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @BorrarButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.Borrar = uibutton(app.Panel, 'push');
            app.Borrar.ButtonPushedFcn = createCallbackFcn(app, @BorrarButtonPushed3, true);
            app.Borrar.Text = 'Borrar';
            app.HELPButton = uibutton(app.Panel_5, 'push');
            app.HELPButton.ButtonPushedFcn = createCallbackFcn(app, @HELPButtonPushed, true);
            app.HELPButton.Text = 'HELP';
            app.Panel_6 = uipanel(app.Panel_5);
            app.UIAxes_2 = uiaxes(app.Panel_6);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.Panel_6);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Panel_7 = uipanel(app.UIFigure);
            app.CorreccinFPButton = uibutton(app.Panel_7, 'push');
            app.CorreccinFPButton.ButtonPushedFcn = createCallbackFcn(app, @CorreccinFPButtonPushed, true);
            app.CorreccinFPButton.Text = 'Corrección F.P';
            app.SistemadeTierrasButton = uibutton(app.Panel_7, 'push');
            app.SistemadeTierrasButton.ButtonPushedFcn = createCallbackFcn(app, @SistemadeTierrasButtonPushed, true);
            app.SistemadeTierrasButton.Text = 'Sistema de Tierras';
            app.SalirButton = uibutton(app.Panel_7, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @SalirButtonPushed, true);
            app.SalirButton.Text = 'Salir';
            app.SistemafotovoltaicoButton = uibutton(app.Panel_7, 'push');
            app.SistemafotovoltaicoButton.ButtonPushedFcn = createCallbackFcn(app, @SistemafotovoltaicoButtonPushed, true);
            app.SistemafotovoltaicoButton.Text = 'Sistema fotovoltaico';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TRANSFORMADA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end